classdef ML_Opt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ML_Photonics_Designer           matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        DefineTargetTab                 matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        CenterX0EditField               matlab.ui.control.NumericEditField
        CenterX0EditFieldLabel          matlab.ui.control.Label
        FWHMEditField                   matlab.ui.control.NumericEditField
        FWHMEditFieldLabel              matlab.ui.control.Label
        LorentzianCalculatorLabel       matlab.ui.control.Label
        ImportSpectrumfromExcelLabel    matlab.ui.control.Label
        Image_Lorentzian                matlab.ui.control.Image
        Switch                          matlab.ui.control.Switch
        TargetDefinitionOptionsLabel    matlab.ui.control.Label
        ImportSpectrumButton            matlab.ui.control.Button
        GenerateDesignTab               matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        IndexEditField                  matlab.ui.control.NumericEditField
        IndexEditFieldLabel             matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        PlotElectricFieldLabel          matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        SimulateDesigninLumericalLabel  matlab.ui.control.Label
        PassTargetintoGeneratorLabel    matlab.ui.control.Label
        SetGeneratorPathLabel           matlab.ui.control.Label
        GenerationandSimulateDesignLabel  matlab.ui.control.Label
        ImportGeneratorButton           matlab.ui.control.Button
        GenerateDesignButton            matlab.ui.control.Button
        OptimizeTab                     matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        MedianFilterSizeSlider          matlab.ui.control.Slider
        MedianFilterSizeSliderLabel     matlab.ui.control.Label
        PerturbationmEditField          matlab.ui.control.NumericEditField
        PerturbationmEditFieldLabel     matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        Polarizationx1y2EditField       matlab.ui.control.NumericEditField
        Polarizationx1y2Label           matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        TargetmEditField                matlab.ui.control.NumericEditField
        TargetmEditFieldLabel           matlab.ui.control.Label
        AdjointOptimizationLabel        matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout1                     matlab.ui.container.GridLayout
        ThicknessLabelValue             matlab.ui.control.NumericEditField
        MaterialLabelValue              matlab.ui.control.NumericEditField
        ThicknessnmLabel                matlab.ui.control.Label
        MaterialLabel                   matlab.ui.control.Label
        ImageGAN                        matlab.ui.control.Image
        TargetSpectrumPlotLabel         matlab.ui.control.Label
        TargetSpectrumPointsLabel       matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MLGeneratedStructureLabel       matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        EMFieldProfileLabel             matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        PreferencesMenu                 matlab.ui.container.Menu
        SetPythonPathMenu               matlab.ui.container.Menu
        SetLumericalWorkingDirectoryMenu  matlab.ui.container.Menu
        SetAPIPathMenu                  matlab.ui.container.Menu
        HelpDocumentationMenu           matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        file_csv;
        path_csv;
        file_m;
        path_m;
        file_netG;
        path_netG;
        x;
        y;
        fileFDFD_csv;
        pathFDFD_csv;
        x2;
        y2;
        fwhm;
        x0;
        pyout;
        datafile;
        genimported;
        rgbImage;
        props;
        sim_absorption;
        index;
        electric;
        DialogApp;
        lumopt_target;
        lumopt_perturbation;
        lumopt_iterations;
        median_filter;
    end
    
    properties (Access = public)
        py_path
        folder_path
        api_path
    end
    
    %For debugging, do this to remove preference: rmpref('ganapppref')
    methods (Access = public)        
        function updatePy(app, path)
            app.py_path = strrep(path, '\', '/');
            setpref('ganapppref', 'py_path', app.py_path);
        end       
        function updateFolder(app, path)
            app.folder_path = strrep(path, '\', '/');
            if app.folder_path(end) ~= '/'
                app.folder_path = append(app.folder_path,'/');
            end
            setpref('ganapppref', 'folder_path', app.folder_path);
        end
        function updateApi(app, path)
            app.api_path = strrep(path, '\', '/');
            setpref('ganapppref', 'api_path', app.api_path);
        end               
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Check preferences on startup.
            if ispref('ganapppref','py_path')
                app.py_path = getpref('ganapppref', 'py_path');
            end
            if ispref('ganapppref','folder_path')
                app.folder_path = getpref('ganapppref', 'folder_path');
            end
            if ispref('ganapppref','api_path')
                app.api_path = getpref('ganapppref', 'api_path');
            end

            app.ML_Photonics_Designer.Icon = 'Icon.PNG';    
            app.Image_Lorentzian.Enable = 'off';
            app.FWHMEditField.Enable = 'off';            
            app.CenterX0EditField.Enable = 'off';
            app.LorentzianCalculatorLabel.Enable = 'off';
            
            app.SetGeneratorPathLabel.Enable = 'off';
            app.ImportGeneratorButton.Enable = 'off';
            app.GenerateDesignButton.Enable = 'off';
            app.PassTargetintoGeneratorLabel.Enable = 'off';
            app.SimulateButton.Enable = 'off';
            app.SimulateDesigninLumericalLabel.Enable = 'off';
            
            app.PlotElectricFieldLabel.Enable = 'off';
            app.PlotButton.Enable = 'off';
            app.IndexEditField.Enable = 'off';
            app.IndexEditFieldLabel.Enable = 'off';
            app.UIAxes3.Visible = 'off';
            
            app.fwhm = 0.75;
            app.x0 = 6;
            app.genimported = 0;
            
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            GuiWidth = 832;
            GuiHeight = 477;
            left = screenWidth*0.5;
            bottom = screenHeight*0.5;
            app.ML_Photonics_Designer.Position = [left-GuiWidth/2 bottom-GuiHeight/2 GuiWidth GuiHeight]; 
            drawnow;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ML_Photonics_Designer.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {477, 477};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {270, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: SetPythonPathMenu
        function SetPythonPathMenuSelected(app, event)
            % Open the options dialog and pass inputs
            app.DialogApp = SetPythonPath(app);
        end

        % Menu selected function: SetLumericalWorkingDirectoryMenu
        function SetLumericalWorkingDirectoryMenuSelected(app, event)
            app.DialogApp = SetFolderPath(app);
        end

        % Menu selected function: SetAPIPathMenu
        function SetAPIPathMenuSelected(app, event)
            app.DialogApp = SetApiPath(app);            
        end

        % Menu selected function: HelpDocumentationMenu
        function HelpDocumentationMenuSelected(app, event)
            web('https://github.com/Raman-Lab-UCLA')
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes3)            
            colorbar(app.UIAxes3, 'off');
            value = app.Switch.Value;
            
            if contains('Lorentz. Calc.',value)
                app.FWHMEditField.Enable = 'on';         
                app.CenterX0EditField.Enable = 'on';  
                app.Image_Lorentzian.Enable = 'on';    
                app.LorentzianCalculatorLabel.Enable = 'on';
                app.ImportSpectrumButton.Enable = 'off';   
                app.ImportSpectrumfromExcelLabel.Enable = 'off'; 
                
                app.x=4:0.01:11.99;
                app.y=zeros(1,800);
                for i = 1:800
                    app.y(i)=(1/pi)*(app.fwhm*0.5)/((app.x(i)-app.x0)^2+(0.5*app.fwhm)^2);
                end
                app.UITable.Data = [app.x.',app.y.'];
                plot(app.UIAxes,app.x,app.y,'LineWidth',3);
                legend(app.UIAxes, 'Target');
                
                app.SetGeneratorPathLabel.Enable = 'on';
                app.ImportGeneratorButton.Enable = 'on';
            end
            if contains('Import Excel',value)
                app.FWHMEditField.Enable = 'off';          
                app.CenterX0EditField.Enable = 'off';
                app.Image_Lorentzian.Enable = 'off';     
                app.LorentzianCalculatorLabel.Enable = 'off';                
                app.ImportSpectrumButton.Enable = 'on';       
                app.ImportSpectrumfromExcelLabel.Enable = 'on';
                
                app.UITable.Data = [];
                cla(app.UIAxes);
                
                if app.genimported == 0
                    app.SetGeneratorPathLabel.Enable = 'off';
                    app.ImportGeneratorButton.Enable = 'off';
                end
            end
            
            app.ImageGAN.Visible = 'off';
            app.Image.Visible = 'on';
            app.Image2.Visible = 'on';
            app.UIAxes3.Visible = 'off';
            app.MaterialLabel.Visible = 'off';
            app.MaterialLabelValue.Visible = 'off';
            app.ThicknessnmLabel.Visible = 'off';
            app.ThicknessLabelValue.Visible = 'off';
            app.SimulateButton.Enable = 'off';
            app.SimulateDesigninLumericalLabel.Enable = 'off';
            app.PlotElectricFieldLabel.Enable = 'off';
            app.PlotButton.Enable = 'off';
            app.IndexEditField.Enable = 'off';
            app.IndexEditFieldLabel.Enable = 'off';
            app.MaterialLabel.Text = 'Material Property (ω or n)';
        end

        % Value changed function: FWHMEditField
        function FWHMEditFieldValueChanged(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes3)            
            colorbar(app.UIAxes3, 'off');

            app.fwhm = app.FWHMEditField.Value;
            
            app.x=4:0.01:11.99;
            app.y=zeros(1,800);
            for i = 1:800
                app.y(i)=(1/pi)*(app.fwhm*0.5)/((app.x(i)-app.x0)^2+(0.5*app.fwhm)^2);
            end
            app.UITable.Data = [app.x.',app.y.'];
            plot(app.UIAxes,app.x,app.y,'LineWidth',3);
            legend(app.UIAxes, 'Target');
            
            app.SetGeneratorPathLabel.Enable = 'on';
            app.ImportGeneratorButton.Enable = 'on';
            
            app.ImageGAN.Visible = 'off';
            app.Image.Visible = 'on';
            app.Image2.Visible = 'on';
            app.UIAxes3.Visible = 'off';
            app.MaterialLabel.Visible = 'off';
            app.MaterialLabelValue.Visible = 'off';
            app.ThicknessnmLabel.Visible = 'off';
            app.ThicknessLabelValue.Visible = 'off';
            app.SimulateButton.Enable = 'off';
            app.SimulateDesigninLumericalLabel.Enable = 'off';
            app.PlotElectricFieldLabel.Enable = 'off';
            app.PlotButton.Enable = 'off';
            app.IndexEditField.Enable = 'off';
            app.IndexEditFieldLabel.Enable = 'off';
            app.MaterialLabel.Text = 'Material Property (ω or n)';
        end

        % Value changed function: CenterX0EditField
        function CenterX0EditFieldValueChanged(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes3)            
            colorbar(app.UIAxes3, 'off');

            app.x0 = app.CenterX0EditField.Value;
            app.TargetmEditField.Value = app.x0;
 
            app.x=4:0.01:11.99;
            app.y=zeros(1,800);
            for i = 1:800
                app.y(i)=(1/pi)*(app.fwhm*0.5)/((app.x(i)-app.x0)^2+(0.5*app.fwhm)^2);
            end
            app.UITable.Data = [app.x.',app.y.'];
            plot(app.UIAxes,app.x,app.y,'LineWidth',3);
            legend(app.UIAxes, 'Target');
            
            app.SetGeneratorPathLabel.Enable = 'on';
            app.ImportGeneratorButton.Enable = 'on';
            
            app.ImageGAN.Visible = 'off';
            app.Image.Visible = 'on';
            app.Image2.Visible = 'on';
            app.UIAxes3.Visible = 'off';
            app.MaterialLabel.Visible = 'off';
            app.MaterialLabelValue.Visible = 'off';
            app.ThicknessnmLabel.Visible = 'off';
            app.ThicknessLabelValue.Visible = 'off';
            app.SimulateButton.Enable = 'off';
            app.SimulateDesigninLumericalLabel.Enable = 'off';
            app.PlotElectricFieldLabel.Enable = 'off';
            app.PlotButton.Enable = 'off';
            app.IndexEditField.Enable = 'off';
            app.IndexEditFieldLabel.Enable = 'off';
            app.MaterialLabel.Text = 'Material Property (ω or n)';
        end

        % Button pushed function: ImportSpectrumButton
        function ImportSpectrumButtonPushed(app, event)
             %Clear Image
            app.Image2.Visible = 'on';
            app.UIAxes3.Visible = 'off';
            cla(app.UIAxes)
            cla(app.UIAxes3)            
            colorbar(app.UIAxes3, 'off');
            
            [app.file_csv,app.path_csv] = uigetfile('*.csv');
            if app.file_csv==0
                % user pressed cancel
                return
            end       
            rawData=readtable(strcat(app.path_csv,app.file_csv));
            app.UITable.Data = rawData(:,1:2);
            app.x=table2array(app.UITable.Data(:,1));
            app.y=table2array(app.UITable.Data(:,2));
            plot(app.UIAxes,app.x,app.y,'LineWidth',3);
            legend(app.UIAxes, 'Target');
            
            [max_abs, max_abs_index] = max(app.y);
            max_abs_wav = app.x(max_abs_index);
            app.TargetmEditField.Value = max_abs_wav;
            
            app.SetGeneratorPathLabel.Enable = 'on';
            app.ImportGeneratorButton.Enable = 'on';
            
            app.ImageGAN.Visible = 'off';
            app.Image.Visible = 'on';
            app.MaterialLabel.Visible = 'off';
            app.MaterialLabelValue.Visible = 'off';
            app.ThicknessnmLabel.Visible = 'off';
            app.ThicknessLabelValue.Visible = 'off';
            app.SimulateButton.Enable = 'off';
            app.SimulateDesigninLumericalLabel.Enable = 'off';
            app.PlotElectricFieldLabel.Enable = 'off';
            app.PlotButton.Enable = 'off';
            app.IndexEditField.Enable = 'off';
            app.IndexEditFieldLabel.Enable = 'off';
            app.MaterialLabel.Text = 'Material Property (ω or n)';
        end

        % Button pushed function: ImportGeneratorButton
        function ImportGeneratorButtonPushed(app, event)
            [app.file_netG,app.path_netG] = uigetfile('*.pt');
            if app.file_netG==0
                % user pressed cancel
                return
            else
                app.PassTargetintoGeneratorLabel.Enable = 'on';
                app.GenerateDesignButton.Enable = 'on';
                app.genimported = 1;
            end
            drawnow;
        end

        % Button pushed function: GenerateDesignButton
        function GenerateDesignButtonPushed(app, event)
        app.median_filter = string(app.MedianFilterSizeSlider.Value);

        if ispref('ganapppref')
            d = uiprogressdlg(app.ML_Photonics_Designer,'Title','Please Wait','Indeterminate','On');
                if ~exist(strcat(app.folder_path,'Temp'), 'dir')
                   mkdir(strcat(app.folder_path,'Temp'))
                end         
           
                if strcmp(app.Switch.Value,'Import Excel')==1
                    app.datafile = fullfile(app.path_csv,app.file_csv);
                    app.datafile = strrep(app.datafile, '\', '/');
                else
                    writematrix(app.UITable.Data,strcat(app.folder_path,'Temp/tempdata.csv'));
                    app.datafile = strcat(app.folder_path,'Temp/tempdata.csv');
                end
                
                % Build Python File
                py0 = sprintf("from __future__ import print_function\nimport torch\nimport torch.nn as nn\nimport torch.nn.parallel\n\n\nclass Generator(nn.Module):\n\tdef __init__(self, ngpu):\n\t\tsuper(Generator, self).__init__()\n\t\tself.ngpu = ngpu\n\n\t\tself.conv1 = nn.ConvTranspose2d(nz, ngf * 8, 6, 1, 0, bias=False)\n\t\tself.conv2 = nn.BatchNorm2d(ngf * 8)\n\t\tself.conv3 = nn.ReLU(True)\n\t\t# state size. (ngf*8) x 6 x 6\n\t\tself.conv4 = nn.ConvTranspose2d(ngf * 8, ngf * 4, 6, 2, 2, bias=False)\n\t\tself.conv5 = nn.BatchNorm2d(ngf * 4)\n\t\tself.conv6 = nn.ReLU(True)\n\t\t# state s7ze. (ngf*4) x 12 x 12\n\t\tself.conv7 = nn.ConvTranspose2d(ngf * 4, ngf * 2, 6, 2, 4, bias=False)\n\t\tself.conv8 = nn.BatchNorm2d(ngf * 2)\n\t\tself.conv9 = nn.ReLU(True)\n\t\t# state size. (ngf*2) x 20 x 20\n\t\tself.conv10 = nn.ConvTranspose2d(ngf * 2, ngf, 6, 2, 5, bias=False)\n\t\tself.conv11 = nn.BatchNorm2d(ngf)\n\t\tself.conv12 = nn.ReLU(True)\n\t\t# state size. (ngf) x 34 x 34\n\t\tself.conv13 = nn.ConvTranspose2d(ngf, nc, 6, 2, 4, bias=False)\n\t\tself.conv14 = nn.Tanh()\n\t\t# state size. (nc) x 64 x 64\n\t#        )\n\n\tdef forward(self, input):\n\t\timageOut = input\n\t\timageOut = self.conv1(imageOut)\n\t\timageOut = self.conv2(imageOut)\n\t\timageOut = self.conv3(imageOut)\n\t\timageOut = self.conv4(imageOut)\n\t\timageOut = self.conv5(imageOut)\n\t\timageOut = self.conv6(imageOut)\n\t\timageOut = self.conv7(imageOut)\n\t\timageOut = self.conv8(imageOut)\n\t\timageOut = self.conv9(imageOut)\n\t\timageOut = self.conv10(imageOut)\n\t\timageOut = self.conv11(imageOut)\n\t\timageOut = self.conv12(imageOut)\n\t\timageOut = self.conv13(imageOut)\n\t\timageOut = self.conv14(imageOut)\n\n\t\treturn imageOut");
            
                % Save to File
                file = fopen(strcat(app.folder_path,'/Temp/GeneratorDef.py'),'w');
                fprintf(file,'%s',py0);
                fclose(file);
                
                
                % Build Python File
                py1 = sprintf('from __future__ import print_function \nimport scipy \nimport scipy.ndimage\nimport torch \nimport torch.nn.parallel \nimport torch.utils.data \nimport matplotlib.pyplot as plt \nimport pandas as pd \nimport cv2 \nimport numpy as np\nfrom GeneratorDef import Generator\n\n');
                py2 = sprintf("netGDir='%s'\n\n",fullfile(app.path_netG,app.file_netG));
                py2 = strrep(py2, '\', '/');
                py3 = sprintf("excelTestData = pd.read_csv('%s', header=None).to_numpy() \n\n", app.datafile);
                py4 = sprintf("netG = torch.load(netGDir, map_location='cpu') \n\nexcelDataSpectra = excelTestData[:800,1] \nexcelTestDataTensor = torch.tensor(excelDataSpectra).type(torch.FloatTensor) \ntestTensor = torch.Tensor() \n\n\ntensor1 = torch.cat((excelTestDataTensor,torch.rand(400))) \ntensor2 = tensor1.unsqueeze(1).unsqueeze(1).unsqueeze(1) \ntensor3 = tensor2.permute(1,0,2,3) \ntestTensor = torch.cat((testTensor,tensor3),0) \n\nfake = netG(testTensor).detach().cpu() \nimg_reshape = fake.permute(0,2,3,1) \nimg = img_reshape.squeeze() \nimg = img.numpy() \nimg = (img + 1)/2 \n\n\n#CALCULATE PROPERTIES \nim_size = 64 \npmax = 4.0 \ntmax = 10.0 \nemax = 5.0 \npsum = 0.0 \npnum = 0.0 \ntsum = 0.0 \ntnum = 0.0 \nesum = 0.0 \nenum = 0.0 \n\nfor row in range(im_size): \n\tfor col in range(im_size): \n\t\tif img[row][col][0] > 0.2 or img[row][col][1] > 0.2: \n\t\t\tif img[row][col][0] > img[row][col][1]: \n\t\t\t\tpsum += img[row][col][0] \n\t\t\t\tpnum += 1 \n\t\t\telse: \n\t\t\t\tesum += img[row][col][1] \n\t\t\t\tenum += 1 \n\t\telse: \n\t\t\ttsum += img[row][col][2] \n\t\t\ttnum += 1 \nif pnum > 0: \n\tpAvg = psum / pnum \nelse: \n\tpAvg = 0.0 \n\nif tnum > 0: \n\ttAvg = tsum / tnum \nelse: \n\ttAvg = 0.0 \n\nif enum > 0: \n\teAvg = esum / enum \nelse: \n\teAvg = 0.0 \n\n\npfake = pmax * pAvg \ntfake = tmax * tAvg \nefake = emax * eAvg \n\n\nif pnum > enum: \n\tpindexfake = pfake \n\tclassifier = 0 \nelse: \n\tpindexfake = efake \n\tclassifier = 1 \n\nplt.imsave('%sTemp/test.png',img) \n\nfile = open('%sTemp/tempproperties.txt','w') \nfile.write(str(pindexfake) + '\\n' + str(tfake) + '\\n' + str(classifier)) \nfile.close()\n\nim_size = 64\nrgb = img\nfor row in range(im_size):\n\tfor col in range(im_size):\n\t\tif rgb[row][col][0] > rgb[row][col][2]:\n\t\t\trgb[row][col][0] = 0\n\t\t\trgb[row][col][1] = 0\n\t\t\trgb[row][col][2] = 0\n\t\telse:\n\t\t\trgb[row][col][0] = 1\n\t\t\trgb[row][col][1] = 1\n\t\t\trgb[row][col][2] = 1\n\ngray = cv2.cvtColor(rgb, cv2.COLOR_BGR2GRAY)\ncv2.normalize(gray, gray, -1, 1, cv2.NORM_MINMAX)\n\n#GAUSSIAN FILTER\nimg_filter = scipy.ndimage.gaussian_filter(gray,sigma=0.75)\nret, img_filter = cv2.threshold(img_filter,0.1,1,cv2.THRESH_BINARY) # 0 = black, 1 = white; everything under first number to black\n\nplt.imsave('%sTemp/gray.png', img_filter, cmap = 'gray')\n\n#LINE DETECTION\nimg = cv2.imread('%sTemp/gray.png')\ngray = cv2.cvtColor(img,cv2.COLOR_BGR2GRAY)\nmedian = cv2.medianBlur(gray,%s)\nedges = cv2.Canny(median,50,150,apertureSize = 3)\nlines = cv2.HoughLinesP(edges,1,np.pi/180,threshold=5,minLineLength=2,maxLineGap=1)\nfor row in lines:\n    for x1,y1,x2,y2 in row:\n        cv2.line(img,(x1,y1),(x2,y2),(0,255,0),1)\ncv2.imwrite('%sTemp/houghlines.png',img)", app.folder_path, app.folder_path, app.folder_path, app.folder_path, app.median_filter, app.folder_path);
                
                % Save to File
                file = fopen(strcat(app.folder_path,'/Temp/netG_Test.py'),'w');
                fprintf(file,'%s%s%s%s',py1, py2, py3, py4);
                fclose(file);
                
                % Run Python File
                command = [app.py_path ' ' app.folder_path '/Temp/netG_Test.py'];
                system(command);
                
                app.rgbImage = imread(strcat(app.folder_path,'Temp/houghlines.png'));
                         
                app.ImageGAN.ImageSource = flipud(app.rgbImage);
                app.ImageGAN.Visible = 'on';
                app.Image.Visible = 'off';
                
                fid = fopen(strcat(app.folder_path,'/Temp/tempproperties.txt'), 'r');
                app.props = fscanf(fid, '%f');
                fclose(fid);
                
                app.MaterialLabelValue.Value = app.props(1);
                app.ThicknessLabelValue.Value = app.props(2)*100;
                if app.props(3) == 0
                    app.MaterialLabel.Text = 'Material Property (ω)';
                else
                    app.MaterialLabel.Text = 'Material Property (n)';
                end
                
                app.MaterialLabel.Visible = 'on';
                app.MaterialLabelValue.Visible = 'on';
                app.ThicknessnmLabel.Visible = 'on';
                app.ThicknessLabelValue.Visible = 'on';
                
                delete(strcat(app.folder_path,'Temp/houghlines.png'));
            close(d);
            
            app.SimulateButton.Enable = 'on';
            app.SimulateDesigninLumericalLabel.Enable = 'on';
            
            app.PlotElectricFieldLabel.Enable = 'on';
            app.PlotButton.Enable = 'on';
            app.IndexEditField.Enable = 'on';
            app.IndexEditFieldLabel.Enable = 'on';
        else
            f = errordlg('Preferences are not set.','Application Error');
        end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            d = uiprogressdlg(app.ML_Photonics_Designer,'Title','Please Wait','Indeterminate','On');
                %imwrite(app.rgbImage, strcat(app.folder_path,'/Temp/gray.png'));
            
                % Build Python File
                py1 = sprintf("#Performs image to binary file conversion for image import in Lumerical\nimport torch\nimport numpy as np\nimport cv2\nimport os\nimport glob\n\n\nimgs = cv2.imread('%sTemp/gray.png')\nimgs = np.float32(imgs)\nimgs = cv2.cvtColor(imgs, cv2.COLOR_BGR2GRAY)\n\n# Turn image array into binary array (black to 1, white to 0)\nbinary = np.zeros(shape = (np.shape(imgs)[0], np.shape(imgs)[1]), dtype = np.uint8)\nimg = imgs[:][:]\nbinary[img[:][:] <= 50] = 1\n\n# doubling the amount of pixels in both dimensions\nresize_fac = 2\nheight, width = imgs.shape\nnew = np.ones(shape = (resize_fac*height, resize_fac*width), dtype = np.uint8)\nnew[:binary.shape[0],:binary.shape[1]] = binary[:][:]\nfor i in range(height-1,-1,-1):\n\tfor j in range(width-1,-1,-1):\n\t\tcur = new[i][j]\n\t\tnew[resize_fac*i:resize_fac*(i+1),resize_fac*j:resize_fac*(j+1)] = [[cur]*resize_fac] * resize_fac\n\nfile1 = open('%sTemp/gray.txt', 'w')\nheader = str(height*resize_fac)+' 1 '+str(height*resize_fac)+' \\n'+str(width*resize_fac)+' 1 '+str(width*resize_fac)+' \\n2 1 2\\n' \nfile1.write(header)\nbody = new.reshape(new.shape[0]*new.shape[1],1)\ncnt=0\nfor item in body:\n\tfile1.write('%ss\\n' %s item[0])\n\tcnt +=1\nfor item in body:\n\tfile1.write('%ss\\n' %s item[0])\n\nfile1.close()", app.folder_path, app.folder_path, '%', '%', '%', '%');
            
                % Save to File
                file = fopen(strcat(app.folder_path,'Temp/img2bin.py'),'w');
                fprintf(file,'%s',py1);
                fclose(file);
                
                % Run Python File
                command = [app.py_path ' ' app.folder_path '/Temp/img2bin.py'];
                system(command);                
                
                % Build Lumerical File
                py1 = sprintf("file = '%sTemp/gray.txt';", app.folder_path);
                py2 = sprintf("\np = %f;",app.props(1));
                py3 = sprintf("\nt = %f;", app.props(2));
                py4 = sprintf("\nclass = %d;", app.props(3));
                py5 = sprintf("\n\nif(class == 0){\n\tload('%sMaster.fsp');\n\tswitchtolayout;\n\taddimport;\n\timportbinary(file, 'microns');  # load file\n\n\tif (p < 2.1155){\n\t\tm = 'Au (Gold) - Palik Copy 1';\n\t}\n\telse if (p > 2.9455){\n\t\tm = 'Al (Aluminium) - CRC';\n\t}\n\telse{\n\t\tm = 'Ag (Silver) - CRC';\n\t}\n\tselect('import');\n\tset('material', m); \n\tset('x span', 3.2e-6); \n\tset('y span', 3.2e-6); \n\tset('z span', 0.1e-6); \n\tset('x', 0); \n\tset('y', 0); \n\tset('z', 0.05e-6);\n\n\n\tselectpartial('substrate::spacer');\n\tset('z min',-t*1e-7);\n\tselectpartial('substrate::mirror');\n\tset('z max',-t*1e-7);\n\tset('z min',(-t-2)*1e-7);\n\tselectpartial('FDTD');\n\tset('z min',(-t-2)*1e-7);\n}\nelse{\n\tload('%sMaster(DielectricMetal).fsp');\n\tswitchtolayout;\n\taddimport;\n\timportbinary(file, 'microns');  # load file\n\n\tif (p < 2.915){\n\t\tm = 'ZnSe';\n\t}\n\telse if (p > 3.715){\n\t\tm = 'Ge (Germanium) - Palik';\n\t}\n\telse{\n\t\tm = 'Si (Silicon) - Palik';\n\t}\n\n\tselect('import');\n\n\tset('material', m); \n\tset('x span', 7.5e-6); \n\tset('y span', 7.5e-6); \n\tset('z min', -0.2e-6); \n\tset('z max', (-2+t)*1e-7);\n\tset('x', 0); \n\tset('y', 0); \n\tset('z', 0.05e-6);\n}\n\n\nsave('Temp/gray.fsp');\n\nselect('import');", app.folder_path, app.folder_path);
                
                % Save to File
                file = fopen(strcat(app.folder_path,'Temp/importgray.lsf'),'w');
                fprintf(file,'%s%s%s%s%s',py1, py2, py3, py4, py5);
                fclose(file);
                
                path(path,app.api_path); 
                h=appopen('fdtd');
                lum_working_path = char(sprintf("addpath('%sTemp');",app.folder_path));
                appevalscript(h,lum_working_path);                
                appevalscript(h,'importgray;');
                appevalscript(h,'run;');
                appevalscript(h,'save;');
                appevalscript(h,'freq = getresult("mon_R","f");');
                appevalscript(h,'lambda = 1e9*3e8/freq;');
                appevalscript(h,'monR = getresult("mon_R","T"); Absorption = 1-monR.T;');
                appevalscript(h,sprintf('matlabsave("%sTemp/gray-Excel.mat", lambda,Absorption);', app.folder_path));
                
                appevalscript(h,'electric = getelectric("monitor_fields");');
                appevalscript(h,sprintf('matlabsave("%sTemp/gray-Field.mat", electric);', app.folder_path));
                %appclose(h);
                
                hold(app.UIAxes, 'on');
                app.sim_absorption = load(strcat(app.folder_path,'Temp/gray-Excel.mat'),'Absorption').Absorption.';
                plot(app.UIAxes,app.x,app.sim_absorption,'LineWidth',3);
                legend(app.UIAxes, 'Target', 'Generated');
                hold(app.UIAxes, 'off');                
            close(d);
            
            app.PlotElectricFieldLabel.Enable = 'on';
            app.PlotButton.Enable = 'on';
            app.IndexEditField.Enable = 'on';
            app.IndexEditFieldLabel.Enable = 'on';
            exportgraphics(app.UIAxes, strcat(app.folder_path,'Temp/TargetGeneratedComparison.png'))
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes)

            app.sim_absorption = load(strcat(app.folder_path,'Temp/gray-Excel.mat'),'Absorption').Absorption.';
            app.index = app.IndexEditField.Value;
            app.electric = load(strcat(app.folder_path,'Temp/gray-Field.mat')).electric;
            
            slice = app.electric(:,:,:,app.index);
            imagesc(app.UIAxes3, rot90(slice));
            colorbar(app.UIAxes3);
            app.UIAxes3.TickLength = [0 0];
            app.UIAxes3.YAxis.TickLength = [0 0];
            clim_max = max(max(slice))/4;
            set(app.UIAxes3, 'CLim', [0 clim_max]);
            xlim(app.UIAxes3, [1 160]);
            ylim(app.UIAxes3, [1 160]);
            
            app.Image2.Visible = 'off';
            app.UIAxes3.Visible = 'on';
            
            hold(app.UIAxes, 'on');            
            x_plot=4:0.08:11.99;
            plot(app.UIAxes,app.x,app.y,'LineWidth',3);
            plot(app.UIAxes,app.x,app.sim_absorption,'LineWidth',3);
            plot(app.UIAxes,ones(1,10)*x_plot(app.index),linspace(0,1,10),'--','LineWidth',2, 'color','red');
            legend(app.UIAxes, 'Target', 'Generated','E-Field Index');
            exportgraphics(app.UIAxes,'C:\Users\ramanlab\Desktop\Ben\ML-Optimization_Integration-main\Lumopt\target.png')
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        app.median_filter = string(app.MedianFilterSizeSlider.Value);

        d = uiprogressdlg(app.ML_Photonics_Designer,'Title','Please Wait','Indeterminate','On');
            if ~exist(strcat(app.folder_path,'LumOpt'), 'dir')
               mkdir(strcat(app.folder_path,'LumOpt'))
            end 

            % Build Python File (gray_edit.py)
            python_api_path = app.api_path;
            python_api_path = strrep(python_api_path, '/', '//');
            python_api_path = strrep(python_api_path, 'matlab', 'python');
            py = sprintf("import sys\nsys.path.insert(0, '%s')\n\nimport lumapi\nimport os\n\nbase_script = '%sTemp/gray.fsp'\nfdtd = lumapi.FDTD(filename = base_script, hide=True)\nfdtd.switchtolayout()\nfdtd.select('import')\nfdtd.delete()\nfdtd.select('mon_R')\nfdtd.delete()\nfdtd.select('monitor_fields')\nfdtd.delete()\n\nfdtd.select('monitor')\nfdtd.delete()\nfdtd.select('FDTD')\nfdtd.set('z max', 4e-6);\nfdtd.set('mesh type', 'auto non-uniform');\nfdtd.set('mesh refinement', 'conformal variant 0');\nfdtd.set('mesh accuracy', 1);\nfdtd.set('dt stability factor',0.99);\nfdtd.set('min mesh step',0.00025e-6);\nfdtd.set('pml profile',1);\nfdtd.select('source')\nfdtd.set('z', 1.8e-6);\n\nfdtd.addmesh();\nfdtd.set('x',0);\nfdtd.set('x span',3.2e-6);\nfdtd.set('y',0);\nfdtd.set('y span',3.2e-6);\nfdtd.set('z min',-0.15e-6);\nfdtd.set('z max',0.25e-6);\nfdtd.set('dx',0.02e-6);\nfdtd.set('dy',0.01e-6);\nfdtd.addpower();\nfdtd.set('name','opt_fields');\nfdtd.set('monitor type','2D Z-normal');\nfdtd.set('x', 0);\nfdtd.set('x span', 3.2e-6);\nfdtd.set('y', 0);\nfdtd.set('y span', 3.2e-6);\nfdtd.set('z', 0.05e-6);\n\nfdtd.addpower();\nfdtd.set('name','fom');\nfdtd.set('monitor type','2D Z-normal');\nfdtd.set('x', 0);\nfdtd.set('x span', 6.4e-6);\nfdtd.set('y', 0);\nfdtd.set('y span', 6.4e-6);\nfdtd.set('z', 1e-6);\n\nfdtd.save('%sLumOpt/gray_new.fsp')",python_api_path, app.folder_path,app.folder_path);
            file = fopen(strcat(app.folder_path,'LumOpt/gray_edit.py'),'w');
            fprintf(file,'%s',py);
            fclose(file);
        
            % Run Python File (gray_edit.py)
            command = [app.py_path ' ' app.folder_path '/LumOpt/gray_edit.py'];
            system(command);

            % Build Python File (polygon_points.py)
            py = sprintf("import numpy as np\nimport matplotlib.pyplot as plt\nimport cv2\nimport math\n\ndef clockwiseangle_and_distance(point):\n    origin = [32, 32]\n    refvec = [0, 1]\n\n    # Vector between point and the origin: v = p - o\n    vector = [point[0]-origin[0], point[1]-origin[1]]\n    # Length of vector: ||v||\n    lenvector = math.hypot(vector[0], vector[1])\n    # If length is zero there is no angle\n    if lenvector == 0:\n        return -math.pi, 0\n    # Normalize vector: v/||v||\n    normalized = [vector[0]/lenvector, vector[1]/lenvector]\n    dotprod  = normalized[0]*refvec[0] + normalized[1]*refvec[1]     # x1*x2 + y1*y2\n    diffprod = refvec[1]*normalized[0] - refvec[0]*normalized[1]     # x1*y2 - y1*x2\n    angle = math.atan2(diffprod, dotprod)\n    # Negative angles represent counter-clockwise angles so we need to subtract them \n    # from 2*pi (360 degrees)\n    if angle < 0:\n        return 2*math.pi+angle, lenvector\n    # I return first the angle because that's the primary sorting criterium\n    # but if two vectors have the same angle then the shorter distance should come first.\n    return angle, lenvector\n\ndef img2polygonpoints(imgpath):\n    img = cv2.imread(imgpath)\n    \n    #LINE DETECTION\n    gray = cv2.cvtColor(img,cv2.COLOR_BGR2GRAY)\n    median = cv2.medianBlur(gray,%s)\n    edges = cv2.Canny(median,50,150,apertureSize = 3)\n    lines = cv2.HoughLinesP(edges,1,np.pi/180,threshold=5,minLineLength=2,maxLineGap=1)\n    for row in lines:\n        for x1,y1,x2,y2 in row:\n            cv2.line(img,(x1,y1),(x2,y2),(0,255,0),1)\n    #cv2.imwrite('houghlines5.jpg',img)\n\n    # 4-col -> 2-col array\n    points = np.vstack((lines[:,0,0:2],lines[:,0,2:4]))\n    #Sort by counterclockwise direction from center\n    points = sorted(points, key=clockwiseangle_and_distance, reverse=True)\n    points = np.vstack(points)\n    \n    #Add linearly interpolated points\n    poly_points = [None, None]\n    for row in range(len(points)):\n        p1= points[row]\n        if row == len(points)-1:\n            p2 = points[0]\n        else:\n            p2 = points[row+1]\n    \n        increment = 4\n        if p2[0]-p1[0] == 0:\n            step_x = 0\n        else:\n            step_x = int(abs(p2[0]-p1[0])/(increment)+1)\n            \n        if p2[1]-p1[1] == 0:\n            step_y = 0\n        else:\n            step_y = int(abs(p2[1]-p1[1])/(increment)+1)\n            \n        step = max(step_x, step_y)\n        x_interp = np.linspace(p1[0], p2[0], step)\n        y_interp = np.linspace(p1[1], p2[1], step)\n        \n        coords = np.column_stack((x_interp,y_interp))\n        poly_points = np.vstack((poly_points,coords[0:len(coords)-1]))\n        \n        \n    poly_points = poly_points[1:]\n    poly_points = np.subtract(poly_points,32) * 1.6e-6 / 32\n        \n    return poly_points",app.median_filter);
            file = fopen(strcat(app.folder_path,'LumOpt/polygon_points.py'),'w');
            fprintf(file,'%s',py);
            fclose(file);            
            
            %Build Python File (transmission_plane.py)
            py = sprintf("import sys\nimport numpy as np\nimport scipy as sp\nimport scipy.constants\nimport lumapi\n\nfrom lumopt.utilities.wavelengths import Wavelengths\n\n''' Helper function to determine if a variable can be converted to an integer '''\ndef is_int(x):\n    try: \n        int(x)\n        return True\n    except ValueError:\n        return False\n\nclass Transmission(object):\n\n    ''' Calculates the figure of merit from an overlap integral between the fields recorded by a field monitor and the slected mode.\n        A mode expansion monitor is added to the field monitor to calculate the overlap result, which appears as T_forward in the \n        list of mode expansion monitor results. The T_forward result is described in the following page:\n        \n            https://kb.lumerical.com/ref_sim_obj_using_mode_expansion_monitors.html\n        \n        This result is equivalent to equation (7) in the following paper:\n        \n           C. Lalau-Keraly, S. Bhargava, O. Miller, and E. Yablonovitch, 'Adjoint shape optimization applied to electromagnetic design,' \n           Opt. Express  21, 21693-21701 (2013). https://doi.org/10.1364/OE.21.021693\n        \n        Parameters\n        ----------\n        :param monitor_name:   name of the field monitor that records the fields to be used in the mode overlap calculation.\n        :param mode_number:    mode number in the list of modes generated by the mode expansion monitor.\n        :param direction:      direction of propagation ('Forward' or 'Backward') of the mode injected by the source.\n        :param multi_freq_src: bool flag to enable / disable a multi-frequency mode calculation and injection for the adjoint source.\n        :param target_T_fwd:   function describing the target T_forward vs wavelength (see documentation for mode expansion monitors).\n        :param norm_p:         exponent of the p-norm used to generate the figure of merit; use to generate the FOM.\n        :param target_fom:     A target value for the figure of merit. This allows to print/plot the distance of the current\n                                   design from a target value\n    '''\n\n    def __init__(self, monitor_name, mode_number, direction, multi_freq_src = False, target_T_fwd = lambda wl: np.ones(wl.size), norm_p = 1, target_fom = 0):\n        self.monitor_name = str(monitor_name)\n        if not self.monitor_name:\n            raise UserWarning('empty monitor name.')\n        self.mode_expansion_monitor_name = monitor_name + '_mode_exp'\n        self.adjoint_source_name = monitor_name + '_mode_src'\n        self.mode_number = mode_number\n        self.target_fom = target_fom\n        \n        if is_int(mode_number):\n            self.mode_number = int(mode_number)\n            if self.mode_number <= 0:\n	            raise UserWarning('mode number should be positive.')\n        else:\n            self.mode_number = mode_number\n        self.direction = str(direction)\n        self.multi_freq_src = bool(multi_freq_src)\n        if self.direction != 'Forward' and self.direction != 'Backward':\n            raise UserWarning('invalid propagation direction.')\n        target_T_fwd_result = target_T_fwd(np.linspace(0.1e-6, 10.0e-6, 1000))\n        if target_T_fwd_result.size != 1000:\n            raise UserWarning('target transmission must return a flat vector with the requested number of wavelength samples.')\n        elif np.any(target_T_fwd_result.min() < 0.0) or np.any(target_T_fwd_result.max() > 1.0):\n            raise UserWarning('target transmission must always return numbers between zero and one.')\n        else:\n            self.target_T_fwd = target_T_fwd\n        self.norm_p = int(norm_p)\n        if self.norm_p < 1:\n            raise UserWarning('exponent p for norm must be positive.')\n\n    def initialize(self, sim):\n        self.check_monitor_alignment(sim)\n        adjoint_injection_direction = 'Backward' if self.direction == 'Forward' else 'Forward'\n        Transmission.add_mode_source(sim, self.monitor_name, self.adjoint_source_name, adjoint_injection_direction, self.mode_number, self.multi_freq_src)\n\n    def make_forward_sim(self, sim):\n        sim.fdtd.setnamed(self.adjoint_source_name, 'enabled', False)\n\n    def make_adjoint_sim(self, sim):\n        sim.fdtd.setnamed(self.adjoint_source_name, 'enabled', True)        \n        \n    def check_monitor_alignment(self, sim):\n      \n        ## Here, we check that the FOM_monitor is properly aligned with the mesh\n        if sim.fdtd.getnamednumber(self.monitor_name) != 1:\n            raise UserWarning('monitor could not be found or the specified name is not unique.')\n        \n        # Get the orientation\n        monitor_type = sim.fdtd.getnamed(self.monitor_name, 'monitor type')\n\n        if (monitor_type == 'Linear X') or (monitor_type == '2D X-normal'):\n            orientation = 'x'\n        elif (monitor_type == 'Linear Y') or (monitor_type == '2D Y-normal'):\n            orientation = 'y'\n        elif (monitor_type == 'Linear Z') or (monitor_type == '2D Z-normal'):\n            orientation = 'z'\n        else:\n            raise UserWarning('monitor should be 2D or linear for a mode expansion to be meaningful.')\n\n        monitor_pos = sim.fdtd.getnamed(self.monitor_name, orientation)\n        if sim.fdtd.getnamednumber('FDTD') == 1:\n            grid = sim.fdtd.getresult('FDTD', orientation)\n        elif sim.fdtd.getnamednumber('varFDTD') == 1:\n            grid = sim.fdtd.getresult('varFDTD', orientation)\n        else:\n            raise UserWarning('no FDTD or varFDTD solver object could be found.')\n        ## Check if this is exactly aligned with the simulation mesh. It exactly aligns if we find a point\n        ## along the grid which is no more than 'tol' away from the position\n        tol = 1e-9\n        dist_from_nearest_mesh_point = min(abs(grid-monitor_pos))\n        if dist_from_nearest_mesh_point > tol:\n            print('WARNING: The monitor ""{}"" is not aligned with the grid. Its distance to the nearest mesh point is {}. This can introduce small phase errors which sometimes result in inaccurate gradients.'.format(self.monitor_name,dist_from_nearest_mesh_point))\n\n\n    @staticmethod\n    def cross_section_monitor_props(monitor_type):\n        geometric_props = ['x', 'y', 'z']\n        normal = ''\n        if monitor_type == '2D X-normal':\n            geometric_props.extend(['y span','z span'])\n            normal = 'x'\n        elif monitor_type == '2D Y-normal':\n            geometric_props.extend(['x span','z span'])\n            normal = 'y'\n        elif monitor_type == '2D Z-normal':\n            geometric_props.extend(['x span','y span'])\n            normal = 'z'\n        elif monitor_type == 'Linear X':\n            geometric_props.append('x span')\n            normal = 'y'\n        elif monitor_type == 'Linear Y':\n            geometric_props.append('y span')\n            normal = 'x'\n        elif monitor_type == 'Linear Z':\n            geometric_props.append('z span')\n        else:\n            raise UserWarning('monitor should be 2D or linear for a mode expansion to be meaningful.')\n        return geometric_props, normal\n\n    def get_fom(self, sim):\n        trans_coeff, self.wavelengths = Transmission.get_transmission_coefficient(sim, self.direction, self.monitor_name, self.mode_expansion_monitor_name)\n        assert np.allclose(self.wavelengths, Transmission.get_wavelengths(sim))\n        source_power = Transmission.get_source_power(sim, self.wavelengths)\n        self.T_fwd_vs_wavelength = np.real(trans_coeff)\n        self.phase_prefactors = trans_coeff / 1.0 \n        fom = Transmission.fom_wavelength_integral(self.T_fwd_vs_wavelength, self.wavelengths, self.target_T_fwd, self.norm_p)\n        return fom\n\n    def get_adjoint_field_scaling(self, sim):\n        omega = 2.0 * np.pi * sp.constants.speed_of_light / self.wavelengths\n        adjoint_source_power = Transmission.get_source_power(sim, self.wavelengths)\n        scaling_factor = np.conj(self.phase_prefactors) * omega * 1j / np.sqrt(adjoint_source_power)\n        return scaling_factor\n\n    @staticmethod\n    def get_wavelengths(sim):\n        return Wavelengths(sim.fdtd.getglobalsource('wavelength start'), \n                           sim.fdtd.getglobalsource('wavelength stop'),\n                           sim.fdtd.getglobalmonitor('frequency points')).asarray()\n\n    @staticmethod\n    def get_transmission_coefficient(sim, direction, monitor_name, mode_exp_monitor_name):\n        if not sim.fdtd.haveresult(monitor_name, 'T'):\n            raise UserWarning('unable to calculate monitor results.')\n        mode_exp_data_set = sim.fdtd.getresult(monitor_name, 'T')\n        mode_exp_wl = mode_exp_data_set['lambda']\n        fwd_trans_coeff = np.abs(mode_exp_data_set['T'])\n        back_trans_coeff = np.abs(mode_exp_data_set['T'])\n        if direction == 'Backward':\n            fwd_trans_coeff, back_trans_coeff = back_trans_coeff, fwd_trans_coeff\n        return fwd_trans_coeff.flatten(), mode_exp_wl.flatten()\n\n    @staticmethod\n    def get_source_power(sim, wavelengths):\n        frequency = sp.constants.speed_of_light / wavelengths\n        source_power = sim.fdtd.sourcepower(frequency)\n        return np.asarray(source_power).flatten()\n\n    @staticmethod\n    def fom_wavelength_integral(T_fwd_vs_wavelength, wavelengths, target_T_fwd, norm_p):\n        target_T_fwd_vs_wavelength = target_T_fwd(wavelengths).flatten()\n        if len(wavelengths) > 1:\n            wavelength_range = wavelengths.max() - wavelengths.min()\n            assert wavelength_range > 0.0, 'wavelength range must be positive.'\n            T_fwd_integrand = np.power(np.abs(target_T_fwd_vs_wavelength), norm_p) / wavelength_range\n            const_term = np.power(np.trapz(y = T_fwd_integrand, x = wavelengths), 1.0 / norm_p)\n            T_fwd_error = np.abs(T_fwd_vs_wavelength.flatten() - target_T_fwd_vs_wavelength)\n            T_fwd_error_integrand = np.power(T_fwd_error, norm_p) / wavelength_range\n            error_term = np.power(np.trapz(y = T_fwd_error_integrand, x = wavelengths), 1.0 / norm_p)\n            fom = const_term - error_term\n        else:\n            fom = np.abs(target_T_fwd_vs_wavelength) - np.abs(T_fwd_vs_wavelength.flatten() - target_T_fwd_vs_wavelength)\n        return fom.real\n\n    @staticmethod\n    def add_mode_source(sim, monitor_name, source_name, direction, mode, multi_freq_src):\n        if sim.fdtd.getnamednumber('FDTD') == 1:\n            sim.fdtd.addplane()            \n        elif sim.fdtd.getnamednumber('varFDTD') == 1:\n            sim.fdtd.addplanesource()\n        else:\n            raise UserWarning('no FDTD or varFDTD solver object could be found.')\n        sim.fdtd.set('name', source_name)\n        sim.fdtd.select(source_name)\n        monitor_type = sim.fdtd.getnamed(monitor_name, 'monitor type')\n        geo_props, normal = Transmission.cross_section_monitor_props(monitor_type)\n        geo_props.remove('x span')\n        geo_props.remove('y span')\n\n        if sim.fdtd.getnamednumber('varFDTD') == 1:\n            geo_props.remove('z')\n        for prop_name in geo_props:\n            prop_val = sim.fdtd.getnamed(monitor_name, prop_name)\n            sim.fdtd.setnamed(source_name, prop_name, prop_val)\n        sim.fdtd.setnamed(source_name, 'override global source settings', False)\n        sim.fdtd.setnamed(source_name, 'x', 0)\n        sim.fdtd.setnamed(source_name, 'y', 0)\n        sim.fdtd.setnamed(source_name, 'x span', 3.6e-6)\n        sim.fdtd.setnamed(source_name, 'y span', 3.6e-6) \n        sim.fdtd.setnamed(source_name, 'direction', 'Backward')        \n        if sim.fdtd.haveproperty('multifrequency mode calculation'):\n            sim.fdtd.setnamed(source_name, 'multifrequency mode calculation', multi_freq_src)\n            if multi_freq_src:\n                sim.fdtd.setnamed(source_name, 'frequency points', sim.fdtd.getglobalmonitor('frequency points'))\n        \n    def fom_gradient_wavelength_integral(self, T_fwd_partial_derivs_vs_wl, wl):\n        assert np.allclose(wl, self.wavelengths)\n        return Transmission.fom_gradient_wavelength_integral_impl(self.T_fwd_vs_wavelength, T_fwd_partial_derivs_vs_wl, self.target_T_fwd(wl).flatten(), self.wavelengths, self.norm_p)\n\n    def fom_gradient_wavelength_integral_on_cad(self, sim, grad_var_name, wl):\n        assert np.allclose(wl, self.wavelengths)\n\n        target_T_fwd_vs_wavelength = self.target_T_fwd(wl).flatten()\n\n        if wl.size > 1:\n            wavelength_range = wl.max() - wl.min()\n            T_fwd_error = self.T_fwd_vs_wavelength - target_T_fwd_vs_wavelength\n            T_fwd_error_integrand = np.power(np.abs(T_fwd_error), self.norm_p) / wavelength_range\n            const_factor = -1.0 * np.power(np.trapz(y = T_fwd_error_integrand, x = wl), 1.0 / self.norm_p - 1.0)\n            integral_kernel = np.power(np.abs(T_fwd_error), self.norm_p - 1) * np.sign(T_fwd_error) / wavelength_range\n            \n            d = np.diff(wl)\n            quad_weight = np.append(np.append(d[0], d[0:-1]+d[1:]),d[-1])/2 #< There is probably a more elegant way to do this\n            v = const_factor * integral_kernel * quad_weight\n\n            lumapi.putMatrix(sim.fdtd.handle, 'wl_scaled_integral_kernel', v)\n            sim.fdtd.eval(('dF_dp_s=size({0});'\n                           'dF_dp2 = reshape(permute({0},[3,2,1]),[dF_dp_s(3),dF_dp_s(2)*dF_dp_s(1)]);'\n                           'T_fwd_partial_derivs=real(mult(transpose(wl_scaled_integral_kernel),dF_dp2));').format(grad_var_name) )\n            T_fwd_partial_derivs_on_cad = sim.fdtd.getv('T_fwd_partial_derivs')\n\n        else:\n            sim.fdtd.eval(('T_fwd_partial_derivs=real({0});').format(grad_var_name) )\n            T_fwd_partial_derivs_on_cad = sim.fdtd.getv('T_fwd_partial_derivs')\n            T_fwd_partial_derivs_on_cad*= -1.0 * np.sign(self.T_fwd_vs_wavelength - target_T_fwd_vs_wavelength)\n\n        return T_fwd_partial_derivs_on_cad.flatten()\n\n\n    @staticmethod\n    def fom_gradient_wavelength_integral_impl(T_fwd_vs_wavelength, T_fwd_partial_derivs_vs_wl, target_T_fwd_vs_wavelength, wl, norm_p):\n\n        if wl.size > 1:\n            assert T_fwd_partial_derivs_vs_wl.shape[1] == wl.size\n            \n            wavelength_range = wl.max() - wl.min()\n            T_fwd_error = T_fwd_vs_wavelength - target_T_fwd_vs_wavelength\n            T_fwd_error_integrand = np.power(np.abs(T_fwd_error), norm_p) / wavelength_range\n            const_factor = -1.0 * np.power(np.trapz(y = T_fwd_error_integrand, x = wl), 1.0 / norm_p - 1.0)\n            integral_kernel = np.power(np.abs(T_fwd_error), norm_p - 1) * np.sign(T_fwd_error) / wavelength_range\n            \n            ## Implement the trapezoidal integration as a matrix-vector-product for performance reasons\n            d = np.diff(wl)\n            quad_weight = np.append(np.append(d[0], d[0:-1]+d[1:]),d[-1])/2 #< There is probably a more elegant way to do this\n            v = const_factor * integral_kernel * quad_weight\n            T_fwd_partial_derivs = T_fwd_partial_derivs_vs_wl.dot(v)\n\n            ## This is the much slower (but possibly more readable) code\n            # num_opt_param = T_fwd_partial_derivs_vs_wl.shape[0]\n            # T_fwd_partial_derivs = np.zeros(num_opt_param, dtype = 'complex')\n            # for i in range(num_opt_param):\n            #     T_fwd_partial_deriv = np.take(T_fwd_partial_derivs_vs_wl.transpose(), indices = i, axis = 1)\n            #     T_fwd_partial_derivs[i] = const_factor * np.trapz(y = integral_kernel * T_fwd_partial_deriv, x = wl)\n        else:\n            T_fwd_partial_derivs = -1.0 * np.sign(T_fwd_vs_wavelength - target_T_fwd_vs_wavelength) * T_fwd_partial_derivs_vs_wl.flatten()\n\n        return T_fwd_partial_derivs.flatten().real");
            file = fopen(strcat(app.folder_path,'LumOpt/transmission_plane.py'),'w');
            fprintf(file,'%s',py);
            fclose(file);            
            
            %Build Python File (optimization.py)
            new_line_char = '\n';
            reg_ex = '\d+$';
            py = sprintf("import os\nimport shutil\nimport inspect\nimport copy\nimport numpy as np\nimport matplotlib.pyplot as plt\nimport re\nfrom multiprocessing.dummy import Pool as ThreadPool\n\nfrom lumopt.utilities.base_script import BaseScript\nfrom lumopt.utilities.wavelengths import Wavelengths\nfrom lumopt.utilities.simulation import Simulation\nfrom lumopt.utilities.fields import FieldsNoInterp\nfrom lumopt.utilities.gradients import GradientFields\nfrom lumopt.figures_of_merit.modematch import ModeMatch\nfrom lumopt.utilities.plotter import Plotter\nfrom lumopt.lumerical_methods.lumerical_scripts import get_fields\n\n\nclass SuperOptimization(object):\n    '''\n        Optimization super class to run two or more co-optimizations targeting different figures of merit that take the same parameters.\n        The addition operator can be used to aggregate multiple optimizations. All the figures of merit are simply added to generate \n        an overall figure of merit that is passed to the chosen optimizer.\n\n        Parameters\n        ----------\n        :param optimizations: list of co-optimizations (each of class Optimization). \n        :param plot_history:  A flag indicating if we should plot the history of the parameters (and gradients)\n    '''\n\n    def __init__(self, optimizations = None, plot_history=False):\n        self.plot_history = bool(plot_history)\n        self.plotter = None                      #< Initialize later, when we are done with adding FOMs\n        self.optimizations=optimizations\n        self.old_dir = os.getcwd()\n        self.full_fom_hist = [] #< Stores the result of every FOM evaluation\n        self.fom_hist = []      #< Only stores the results of every iteration of the optimizer (i.e. not intermediate results from line-searches etc.)\n        self.params_hist=[]     #< List of parameters after iterations\n        self.grad_hist =[]\n        self.continuation_max_iter = 20\n\n    def __add__(self,other):\n        if self.optimizations is not None:\n            opt_list = self.optimizations\n            opt_list.append(other)\n            return SuperOptimization(opt_list, self.plot_history)\n        else:\n            return SuperOptimization([self,other], self.plot_history)\n\n    def __del__(self):\n        os.chdir(self.old_dir)\n\n    def initialize(self, start_params=None, bounds=None, working_dir=None):\n\n        print('Initializing super optimization')\n        working_dir = 'superopt' if working_dir is None else working_dir\n        self.prepare_working_dir(working_dir)\n\n        ## Generate a super-optimizer by making a deep copy of the first optimizer and then modifying it\n        self.optimizer = copy.deepcopy(self.optimizations[0].optimizer)\n        self.optimizer.logging_path = self.workingDir #< Logging from the main optimizer should land in the common paths for super-optimizations\n        self.target_fom = sum([o.fom.target_fom for o in self.optimizations]) #< Sum of all target_foms \n\n        self.fom_names = ['total']\n\n        fom_plot_style = [o.plot_fom_on_log_scale for o in self.optimizations]\n        self.plot_fom_on_log_scale = all(fom_plot_style)\n\n        ## Initialize the individual sub-optimizations\n        def init_suboptimization(cur_optimization):\n            cur_optimization.optimizer.scale_initial_gradient_to = 0 #< Disable automatic gradient scaling for individual FOMs. Can still be applied on combined FOM\n            local_working_dir = os.path.join(self.workingDir,'opts')\n            cur_optimization.initialize(local_working_dir)\n            legend_entry = cur_optimization.label if cur_optimization.label else cur_optimization.fom.monitor_name\n            self.fom_names.append(legend_entry)\n\n        list(map(init_suboptimization, self.optimizations))\n        # with ThreadPool(self.num_threads) as pool:\n        #     pool.map(func = init_suboptimization, iterable = self.optimizations)\n\n        if start_params is None:\n            start_params = self.optimizations[0].geometry.get_current_params()\n        if bounds is None:\n            bounds = np.array(self.optimizations[0].geometry.bounds)\n\n        def callable_fom(params):\n            self.optimizations[0].sim.fdtd.clearjobs()\n            print('Making forward solves')\n            def make_forward_solve(optimization, opt_iter = self.optimizer.iteration, params = params):\n                jobs = list()\n                iter = opt_iter if optimization.store_all_simulations else 0\n                forward_job_name = optimization.make_forward_sim(params, iter)\n                jobs.append(forward_job_name)\n                if optimization.optimizer.concurrent_adjoint_solves():\n                    adjoint_job_name = optimization.make_adjoint_sim(params, iter)\n                    jobs.append(adjoint_job_name)\n                return jobs\n            with ThreadPool(self.num_threads) as pool:\n                nested_job_list = pool.map(func = make_forward_solve, iterable = self.optimizations)\n            for job_list in nested_job_list:\n                for job in job_list:\n                    self.optimizations[0].sim.fdtd.addjob(job)\n            print('Running solves')\n            self.optimizations[0].sim.fdtd.runjobs()\n            print('Processing forward solves')\n\n            def process_forward_solve(optimization, opt_iter = self.optimizer.iteration):\n                iter = self.optimizer.iteration if optimization.store_all_simulations else 0\n                return optimization.process_forward_sim(iter)\n\n            fom_list = list(map(process_forward_solve, self.optimizations))\n            combined_fom = sum(fom_list)\n\n            dist_to_target_fom = self.target_fom - combined_fom  #< For plotting/logging we store the distance to a target\n            self.full_fom_hist.append(dist_to_target_fom) \n            print('=> Combined FOM = {} ({})'.format(dist_to_target_fom, combined_fom))\n            return combined_fom\n\n        def callable_jac(params):\n            self.optimizations[0].sim.fdtd.clearjobs()\n            print('Making adjoint solves')\n            def make_adjoint_solves(arg_pair, opt_iter = self.optimizer.iteration, params = params):\n                idx, optimization = arg_pair[0], arg_pair[1]\n                jobs = list()\n                iter = opt_iter if optimization.store_all_simulations else 0\n                no_forward_fields = not hasattr(optimization,'forward_fields')\n                params_changed = not np.allclose(params, optimization.geometry.get_current_params())\n                redo_forward_sim = no_forward_fields or params_changed\n                do_adjoint_sim = redo_forward_sim or not optimization.optimizer.concurrent_adjoint_solves() or optimization.forward_fields.iter != iter\n                if redo_forward_sim:\n                    forward_job_name = optimization.make_forward_sim(params, iter)\n                    jobs.append(forward_job_name)\n                if do_adjoint_sim:\n                    adjoint_job_name = optimization.make_adjoint_sim(params, iter)\n                    jobs.append(adjoint_job_name)\n                return idx, redo_forward_sim, jobs\n            with ThreadPool(self.num_threads) as pool:\n                nested_job_list = pool.map(func = make_adjoint_solves, iterable = enumerate(self.optimizations))\n            redo_forward_sim_dict = dict()\n            for idx,redo_fwd,job_list in nested_job_list:\n                redo_forward_sim_dict[idx] = redo_fwd\n                for job in job_list:\n                    self.optimizations[0].sim.fdtd.addjob(job)\n            if len(self.optimizations[0].sim.fdtd.listjobs()) > 0:\n                print('Running solves')\n                self.optimizations[0].sim.fdtd.runjobs()\n            print('Processing adjoint solves')\n            def process_adjoint_solves(arg_pair, redo_fwd = redo_forward_sim_dict, opt_iter = self.optimizer.iteration):\n                idx, optimization = arg_pair[0], arg_pair[1]\n                iter = opt_iter if optimization.store_all_simulations else 0\n                if redo_fwd[idx]:\n                    optimization.process_forward_sim(iter)\n                optimization.process_adjoint_sim(iter)\n                jac = optimization.calculate_gradients()\n                return np.array(jac)\n\n            with ThreadPool(self.num_threads) as pool:\n                jac_list = pool.map(process_adjoint_solves, enumerate(self.optimizations))\n            self.last_grad = sum(jac_list)\n\n            #self.full_grad_hist.append(copy.copy(combined_jac))\n            return self.last_grad\n\n        def plotting_function(params):\n            ''' This function is called after each iteration'''\n            \n            ## Add the last FOM evaluation to the list of FOMs that we wish to plot. This removes entries caused by linesearches etc.\n            self.params_hist.append(params)\n            self.fom_hist.append(self.full_fom_hist[-1])\n            self.grad_hist.append(self.last_grad / self.optimizer.scaling_factor)\n\n            for optimization in self.optimizations:\n                optimization.plotting_function(params)\n                #optimization.optimizer.callback((params))\n            \n            ## == Forward the calls to the individual optimizations to show each individual FOM ==\n            self.plotter.clear()\n            self.plotter.update_fom(self)                   #< Plot the total FOM first so it always has the same line color\n            for optimization in self.optimizations:\n                self.plotter.update_fom(optimization)       #< Plot individual FOMs as well\n                self.plotter.update_geometry(optimization)  #< Also plot geometry, e.g. to show over-etch/under-etch\n            self.plotter.set_legend(self.fom_names)\n\n            self.plotter.update_gradient(self)\n            self.plotter.draw_and_save()                    #< Finally, refresh the screen and save the image\n\n            for optimization in self.optimizations:\n                optimization.save_index_to_vtk(self.optimizer.iteration)\n\n            if hasattr(self.optimizations[0].geometry,'to_file'):\n                self.optimizations[0].geometry.to_file(os.path.join(self.workingDir,'parameters_{}.npz').format(self.optimizer.iteration))\n\n            with open(os.path.join(self.workingDir,'convergence_report.txt'),'a') as f:\n                f.write('{}, {}'.format(self.optimizer.iteration,self.fom_hist[-1]))\n                if hasattr(self.optimizations[0].geometry,'write_status'):\n                    self.optimizations[0].geometry.write_status(f) \n                if len(self.params_hist[-1])<250:\n                    f.write(', {}'.format(np.array2string(self.params_hist[-1], separator=', ', max_line_width=10000)))\n                if len(self.grad_hist[-1])<250:\n                    f.write(', {}'.format(np.array2string(self.grad_hist[-1], separator=', ', max_line_width=10000)))\n                f.write('%s')\n   \n        if hasattr(self.optimizer,'initialize'):\n            self.optimizer.initialize(start_params=start_params,\n                                      callable_fom=callable_fom,\n                                      callable_jac=callable_jac,\n                                      bounds=bounds,\n                                      plotting_function=plotting_function)\n\n    def init_plotter(self):\n        if self.plotter is None:\n            self.plotter = Plotter(movie = True, plot_history = self.plot_history)\n\n    def plot_fom(self, fomax, paramsax, gradients_ax):\n\n        if self.plot_fom_on_log_scale:\n            fomax.semilogy(np.abs(self.fom_hist))\n        else:\n            fomax.plot(np.abs(self.fom_hist))\n        \n        fomax.set_xlabel('Iteration')\n        fomax.set_title('Figure of Merit')\n        fomax.set_ylabel('FOM')\n\n        if paramsax is not None:\n            paramsax.clear()\n            paramsax.semilogy(np.abs(self.params_hist))\n            paramsax.set_xlabel('Iteration')\n            paramsax.set_ylabel('Parameters')\n            paramsax.set_title('Parameter evolution')\n    \n        if (gradients_ax is not None) and hasattr(self, 'grad_hist'):\n            gradients_ax.clear()\n            gradients_ax.semilogy(np.abs(self.grad_hist))\n            gradients_ax.set_xlabel('Iteration')\n            gradients_ax.set_ylabel('Gradient Magnitude')\n            gradients_ax.set_title('Gradient evolution')\n\n    def plot_gradient(self, fig, ax_fields, ax_gradients):\n\n        self.optimizations[0].gradient_fields.forward_fields.plot(ax_fields, title = 'Forward Fields', cmap = 'Blues')\n\n        ax_gradients.clear()\n        main_grad = self.optimizations[0].gradient_fields\n        x = main_grad.forward_fields.x\n        y = main_grad.forward_fields.y\n        xx, yy = np.meshgrid(x, y)\n        \n        combined_gradients = 0\n        for optimization in self.optimizations:\n            combined_gradients = combined_gradients + optimization.gradient_fields.get_forward_dot_adjoint_center()\n\n        im = ax_gradients.pcolormesh(xx*1e6, yy*1e6, combined_gradients, cmap = plt.get_cmap('bwr'))\n        ax_gradients.set_title('Sparse perturbation gradient fields')\n        ax_gradients.set_xlabel('x(um)')\n        ax_gradients.set_ylabel('y(um)')\n\n\n    def prepare_working_dir(self, working_dir):\n\n        ## Check if we have an absolute path\n        if not os.path.isabs(working_dir):\n            ## If not, we assume it is meant relative to the path of the script which called this script\n            working_dir = os.path.abspath(os.path.join(self.base_file_path,working_dir))\n        \n        ## Check if the provided path already ends with _xxxx (where xxxx is a number)\n        result = re.match(r'_%s', working_dir)\n        without_suffix = re.sub(r'_%s', '', working_dir)\n        suffix_num = int(result[1:]) if result else 0\n        working_dir = without_suffix+'_{}'.format(suffix_num)\n\n        ## Check if path already exists. If so, keep increasing the number until it does not exist\n        while os.path.exists(working_dir):\n            suffix_num += 1\n            working_dir = without_suffix+'_{}'.format(suffix_num)\n\n        os.makedirs(working_dir)\n        os.chdir(working_dir)\n        \n        ## Copy the calling script over\n        if os.path.isfile(self.calling_file_name):\n            shutil.copy(self.calling_file_name, working_dir)\n\n        self.workingDir = working_dir\n\n    def run(self, working_dir = None, num_threads = None):\n        ''' Runs the co-optimization concurrently. The number of threads sets the size of the thread pool used to schedule \n            CAD operations (e.g. loading and saving files). One CAD is launched for every added optimization object, and the \n            number of threads determines how many of them will be active at the same time. All the simulations required for \n            every fom/jac evaluation are scheduled through the job manager (part of the CAD), so the resource configuration\n            set there is what will determine how many simulations are run in parallel.\n\n        Parameters\n        ----------\n        :param num_threads: sets the number of concurrent CAD operations; defaults to the number of added optimization objects.\n        :param working_dir: directory where the simulation files and other output is stored\n        '''\n\n        self.num_threads = int(num_threads) if num_threads else len(self.optimizations)\n        if self.num_threads < 1:\n            raise UserWarning('number of threads must be positive.')\n\n        ## Figure out from which file this method was called (most likely the driver script)\n        frame = inspect.stack()[1]\n        self.calling_file_name = os.path.abspath(frame[0].f_code.co_filename)\n        self.base_file_path = os.path.dirname(self.calling_file_name)\n\n        self.initialize(working_dir=working_dir)\n        self.init_plotter()\n        \n        if self.plotter.movie:\n            with self.plotter.writer.saving(self.plotter.fig, os.path.join(self.workingDir,'optimization.png'), 100):\n                self.optimizer.run()\n        else:\n            self.optimizer.run()\n\n        ## For topology optimization we are not done yet ... \n        if hasattr(self.optimizations[0].geometry,'progress_continuation'):\n            print(' === Starting Binarization Phase === ')\n            self.optimizer.max_iter=self.continuation_max_iter\n\n            ## We only want a list of unique separate instances (not references to the same geometry)\n            geo_list = [cur_opt.geometry for cur_opt in self.optimizations]\n            unique_geometries = {id(x): x for x in geo_list}.values()\n\n            ## Check if any one of the gemetries still needs to continue. If so ... keep at it.\n            while any( [cur_geo.progress_continuation() for cur_geo in unique_geometries]):\n                for optimization in self.optimizations:\n                    optimization.optimizer.reset_start_params(self.params_hist[-1], 0)\n                new_scaling = self.optimizer.scale_initial_gradient_to / 1.1 #< Slowly reduce the scaling. TODO: Could make sense to limit this somehow\n                self.optimizer.reset_start_params(self.params_hist[-1], new_scaling)\n\n                self.optimizer.run()\n                \n        final_fom = np.abs(self.fom_hist[-1])\n        return final_fom,self.params_hist[-1]\n\n\nclass Optimization(SuperOptimization):\n    ''' Acts as orchestrator for all the optimization pieces. Calling the member function run will perform the optimization,\n        which requires four key pieces: \n            1) a script to generate the base simulation,\n            2) an object that defines and collects the figure of merit,\n            3) an object that generates the shape under optimization for a given set of optimization parameters and\n            4) a gradient based optimizer.\n\n        Parameters\n        ----------\n        :param base_script:    callable, file name or plain string with script to generate the base simulation.\n        :param wavelengths:    wavelength value (float) or range (class Wavelengths) with the spectral range for all simulations.\n        :param fom:            figure of merit (class ModeMatch).\n        :param geometry:       optimizable geometry (class FunctionDefinedPolygon).\n        :param optimizer:      SciyPy minimizer wrapper (class ScipyOptimizers).\n        :param hide_fdtd_cad:  flag run FDTD CAD in the background.\n        :param use_deps:       flag to use the numerical derivatives calculated directly from FDTD.\n        :param plot_history:   plot the history of all parameters (and gradients)\n        :param store_all_simulations: Indicates if the project file for each iteration should be stored or not \n        :param save_global_index: Should the project save the result of a global index monitor to file after each iteration (for visualization purposes)\n        :param label:          If the optimization is part of a super-optimization, this string is used for the legend of the corresponding FOM plot \n    '''\n\n    def __init__(self, base_script, wavelengths, fom, geometry, optimizer, use_var_fdtd = False, hide_fdtd_cad = False, use_deps = True, plot_history = True, store_all_simulations = True, save_global_index = False, label=None):\n        super().__init__(plot_history=plot_history)\n        self.base_script = base_script if isinstance(base_script, BaseScript) else BaseScript(base_script)\n        self.wavelengths = wavelengths if isinstance(wavelengths, Wavelengths) else Wavelengths(wavelengths)\n        self.fom = fom\n        self.geometry = geometry\n        self.optimizer = optimizer\n        self.use_var_fdtd = bool(use_var_fdtd)\n        self.hide_fdtd_cad = bool(hide_fdtd_cad)\n\n        if callable(use_deps):\n            self.use_deps = True\n            self.custom_deps = use_deps\n        else:\n            self.use_deps = bool(use_deps)\n            self.custom_deps = None\n\n        self.store_all_simulations = store_all_simulations\n        self.save_global_index  = save_global_index\n        self.unfold_symmetry = geometry.unfold_symmetry\n        self.label=label\n        self.plot_fom_on_log_scale = (float(fom.target_fom) != 0.0)\n\n        if self.use_deps:\n            print('Accurate interface detection enabled')\n\n        ## Figure out from which file this method was called (most likely the driver script)\n        frame = inspect.stack()[1]\n        self.calling_file_name = os.path.abspath(frame[0].f_code.co_filename)\n        self.base_file_path = os.path.dirname(self.calling_file_name)\n\n    def run(self, working_dir = None):\n            \n        self.initialize(working_dir)\n        self.init_plotter()\n        if self.plotter.movie:\n            with self.plotter.writer.saving(self.plotter.fig, os.path.join(self.workingDir,'optimization.png'), 100):\n                self.optimizer.run()\n        else:\n            self.optimizer.run()\n\n        ## For topology optimization we are not done yet ... \n        if hasattr(self.geometry,'progress_continuation'):\n            print(' === Starting Binarization Phase === ')\n            self.optimizer.max_iter=self.continuation_max_iter\n            while self.geometry.progress_continuation():\n                self.optimizer.reset_start_params(self.params_hist[-1], 0.05) #< Run the scaling analysis again\n                self.optimizer.run()\n\n        final_fom = np.abs(self.fom_hist[-1])\n        return final_fom,self.params_hist[-1]\n\n    def plotting_function(self, params):\n        ## Add the last FOM evaluation to the list of FOMs that we wish to plot. This removes entries caused by linesearches etc.\n        self.fom_hist.append(self.full_fom_hist[-1])\n\n        ## In a multi-FOM optimization, only the first optimization has a plotter\n        if self.plotter is not None:\n\n            self.params_hist.append(params)\n            self.grad_hist.append(self.last_grad / self.optimizer.scaling_factor)\n\n            self.plotter.clear()\n            self.plotter.update_fom(self)\n            self.plotter.update_gradient(self)\n            self.plotter.update_geometry(self)\n            self.plotter.draw_and_save()\n\n            self.save_index_to_vtk(self.optimizer.iteration)\n\n            if hasattr(self.geometry,'to_file'):\n                self.geometry.to_file(os.path.join(self.workingDir,'parameters_{}.npz').format(self.optimizer.iteration))\n\n            with open(os.path.join(self.workingDir,'convergence_report.txt'),'a') as f:\n                f.write('{}, {}'.format(self.optimizer.iteration,self.fom_hist[-1]))\n\n                if hasattr(self.geometry,'write_status'):\n                    self.geometry.write_status(f) \n\n                if len(self.params_hist[-1])<250:\n                    f.write(', {}'.format(np.array2string(self.params_hist[-1], separator=', ', max_line_width=10000)))\n\n                if len(self.grad_hist[-1])<250:\n                    f.write(', {}'.format(np.array2string(self.grad_hist[-1], separator=', ', max_line_width=10000)))\n\n                f.write('%s')\n\n    def initialize(self, working_dir):\n        ''' \n            Performs all steps that need to be carried only once at the beginning of the optimization. \n        '''\n        working_dir = 'opts' if working_dir is None else working_dir\n        self.prepare_working_dir(working_dir)\n\n        ## Store a copy of the script file\n        if hasattr(self.base_script, 'script_str'):\n            with open('script_file.lsf','a') as file:\n                file.write(self.base_script.script_str.replace(';',';%s'))\n\n        # FDTD CAD\n        # WARNING: NOT THREAD SAFE, ADD LOCK\n        #lock.acquire()\n        self.sim = Simulation(self.workingDir, self.use_var_fdtd, self.hide_fdtd_cad)\n        self.geometry.check_license_requirements(self.sim)\n        #lock.release()\n\n        # FDTD model\n        self.base_script(self.sim.fdtd)\n        Optimization.set_global_wavelength(self.sim, self.wavelengths)\n        Optimization.set_source_wavelength(self.sim, 'source', self.fom.multi_freq_src, len(self.wavelengths))\n        self.sim.fdtd.setnamed('opt_fields', 'override global monitor settings', False)\n        self.sim.fdtd.setnamed('opt_fields', 'spatial interpolation', 'none')\n        Optimization.add_index_monitor(self.sim, 'opt_fields')\n        \n        if self.use_deps:\n            Optimization.set_use_legacy_conformal_interface_detection(self.sim, False)\n\n        # Optimizer\n        start_params = self.geometry.get_current_params()\n\n        # We need to add the geometry first because it adds the mesh override region\n        self.geometry.add_geo(self.sim, start_params, only_update = False)\n\n        # If we don't have initial parameters yet, try to extract them from the simulation (this is mostly for topology optimization)\n        if start_params is None:\n            self.geometry.extract_parameters_from_simulation(self.sim)\n            start_params = self.geometry.get_current_params()\n\n        callable_fom = self.callable_fom\n        callable_jac = self.callable_jac\n        bounds = self.geometry.bounds\n\n        self.fom.initialize(self.sim)\n\n        def plotting_function_fwd(params):\n             self.plotting_function(params)\n\n        self.optimizer.initialize(start_params = start_params, callable_fom = callable_fom, callable_jac = callable_jac, bounds = bounds, plotting_function = plotting_function_fwd)\n        \n        self.fom_hist = []\n      \n\n    def save_index_to_vtk(self, cur_iteration):\n        if self.save_global_index:\n            self.sim.save_index_to_vtk(os.path.join(self.workingDir,'global_index_{}').format(cur_iteration))\n\n    def make_forward_sim(self, params, iter):\n        self.sim.fdtd.switchtolayout()\n        self.geometry.update_geometry(params, self.sim)\n        self.geometry.add_geo(self.sim, params = None, only_update = True)\n        self.sim.fdtd.setnamed('source', 'enabled', True)\n        self.fom.make_forward_sim(self.sim)\n        forward_name = 'forward_{}'.format(iter)\n        return self.sim.save(forward_name)\n\n    def process_forward_sim(self, iter):\n        forward_name = 'forward_{}'.format(iter)\n        self.sim.load(forward_name)\n        Optimization.check_simulation_was_successful(self.sim)\n        self.forward_fields = get_fields(self.sim.fdtd,\n                                         monitor_name = 'opt_fields',\n                                         field_result_name = 'forward_fields',\n                                         get_eps = True,\n                                         get_D = not self.use_deps,\n                                         get_H = False,\n                                         nointerpolation = not self.geometry.use_interpolation(),\n                                         unfold_symmetry = self.unfold_symmetry)\n        assert hasattr(self.forward_fields, 'E')\n        fom = self.fom.get_fom(self.sim)\n        self.forward_fields.iter = int(iter)\n        if self.store_all_simulations:\n            self.sim.remove_data_and_save() #< Remove the data from the file to save disk space. TODO: Make optional?\n        \n        dist_to_target_fom = self.fom.target_fom - fom  #< For plotting/logging we store the distance to a target\n        self.full_fom_hist.append(dist_to_target_fom) \n        if self.fom.target_fom == 0.0:\n            print('FOM = {}'.format(fom))\n        else:\n            print('FOM = {} ({} - {})'.format(dist_to_target_fom, self.fom.target_fom, fom))\n        return fom\n\n    def callable_fom(self, params):\n        ''' Function for the optimizers to retrieve the figure of merit.\n            :param params:  optimization parameters.\n            :param returns: figure of merit.\n        '''\n\n        self.sim.fdtd.clearjobs()\n        iter = self.optimizer.iteration if self.store_all_simulations else 0\n        print('Making forward solve')\n        forward_job_name = self.make_forward_sim(params, iter)\n        self.sim.fdtd.addjob(forward_job_name)\n        if self.optimizer.concurrent_adjoint_solves():\n            print('Making adjoint solve')\n#            adjoint_job_name = self.make_adjoint_sim(params, iter)\n#            self.sim.fdtd.addjob(adjoint_job_name)\n        print('Running solves')\n        self.sim.fdtd.runjobs()\n        print('Processing forward solve')\n        return self.process_forward_sim(iter)\n\n#    def make_adjoint_sim(self, params, iter):\n#        assert np.allclose(params, self.geometry.get_current_params())\n#        adjoint_name = 'adjoint_{}'.format(iter)\n#        self.sim.fdtd.switchtolayout()\n#        self.geometry.add_geo(self.sim, params = None, only_update = True)\n#        self.sim.fdtd.setnamed('source', 'enabled', False)\n#        self.fom.make_adjoint_sim(self.sim)\n#        return self.sim.save(adjoint_name)\n\n    def process_adjoint_sim(self, iter):\n        adjoint_name = 'forward_{}'.format(iter)\n        self.sim.load(adjoint_name)\n        if self.sim.fdtd.layoutmode():\n            self.sim.fdtd.run()\n        Optimization.check_simulation_was_successful(self.sim)\n        self.adjoint_fields = get_fields(self.sim.fdtd,\n                                         monitor_name = 'opt_fields',\n                                         field_result_name = 'adjoint_fields',\n                                         get_eps = not self.use_deps,\n                                         get_D = not self.use_deps,\n                                         get_H = False,\n                                         nointerpolation = not self.geometry.use_interpolation(),\n                                         unfold_symmetry = self.unfold_symmetry)\n        assert hasattr(self.adjoint_fields, 'E')\n        self.adjoint_fields.iter = int(iter)\n        self.adjoint_fields.scaling_factor = self.fom.get_adjoint_field_scaling(self.sim)\n        self.adjoint_fields.scale(3, self.adjoint_fields.scaling_factor)\n        if self.store_all_simulations:\n            self.sim.remove_data_and_save() #< Remove the data from the file to save disk space. TODO: Make optional?\n\n    def callable_jac(self, params):\n        ''' Function for the optimizer to extract the figure of merit gradient.\n            :param params:  optimization paramaters.\n            :param returns: partial derivative of the figure of merit with respect to each optimization parameter.\n        '''\n\n        self.sim.fdtd.clearjobs()\n        iter = self.optimizer.iteration if self.store_all_simulations else 0\n        no_forward_fields = not hasattr(self,'forward_fields')\n        params_changed = not np.allclose(params, self.geometry.get_current_params())\n        redo_forward_sim = no_forward_fields or params_changed\n        do_adjoint_sim = redo_forward_sim or not self.optimizer.concurrent_adjoint_solves() or self.forward_fields.iter != iter \n        if redo_forward_sim:\n            print('Making forward solve')\n            forward_job_name = self.make_forward_sim(params, iter)\n            self.sim.fdtd.addjob(forward_job_name)\n        if do_adjoint_sim:\n            print('Making adjoint solve')\n            adjoint_job_name = self.make_adjoint_sim(params, iter)\n            self.sim.fdtd.addjob(adjoint_job_name)\n        if len(self.sim.fdtd.listjobs()) > 0:\n            print('Runing solves')\n            self.sim.fdtd.runjobs()\n        if redo_forward_sim:\n            print('Processing forward solve')\n            fom = self.process_forward_sim(iter)\n        print('Processing adjoint solve')\n        self.process_adjoint_sim(iter)\n        print('Calculating gradients')\n        self.last_grad = self.calculate_gradients()\n        return self.last_grad\n\n    def calculate_gradients(self):\n        ''' Calculates the gradient of the figure of merit (FOM) with respect to each of the optimization parameters.\n            It assumes that both the forward and adjoint solves have been run so that all the necessary field results\n            have been collected. There are currently two methods to compute the gradient:\n                1) using the permittivity derivatives calculated directly from meshing (use_deps == True) and\n                2) using the shape derivative approximation described in Owen Miller's thesis (use_deps == False).\n        '''\n\n        self.gradient_fields = GradientFields(forward_fields = self.forward_fields, adjoint_fields = self.adjoint_fields)\n        self.sim.fdtd.switchtolayout()\n        if self.use_deps:\n            if self.custom_deps:\n                self.custom_deps(self.sim,self.geometry)\n            else:\n                self.geometry.d_eps_on_cad(self.sim)\n\n            fom_partial_derivs_vs_wl = GradientFields.spatial_gradient_integral_on_cad(self.sim, 'forward_fields', 'adjoint_fields', self.adjoint_fields.scaling_factor)\n            self.gradients = self.fom.fom_gradient_wavelength_integral(fom_partial_derivs_vs_wl.transpose(), self.forward_fields.wl)\n        else:\n            if hasattr(self.geometry,'calculate_gradients_on_cad'):\n                grad_name = self.geometry.calculate_gradients_on_cad(self.sim, 'forward_fields', 'adjoint_fields', self.adjoint_fields.scaling_factor)               \n                self.gradients = self.fom.fom_gradient_wavelength_integral_on_cad(self.sim, grad_name, self.forward_fields.wl)\n            else:\n                fom_partial_derivs_vs_wl = self.geometry.calculate_gradients(self.gradient_fields)\n                self.gradients = self.fom.fom_gradient_wavelength_integral(fom_partial_derivs_vs_wl, self.forward_fields.wl)\n        return self.gradients\n    \n    def plot_gradient(self, fig, ax1, ax2):\n        self.gradient_fields.plot(fig, ax1, ax2)\n\n    @staticmethod\n    def add_index_monitor(sim, monitor_name):\n        sim.fdtd.select(monitor_name)\n        if sim.fdtd.getnamednumber(monitor_name) != 1:\n            raise UserWarning('a single object named ""{}"" must be defined in the base simulation.'.format(monitor_name))\n        index_monitor_name = monitor_name + '_index'\n        if sim.fdtd.getnamednumber('FDTD') == 1:\n            sim.fdtd.addindex()\n        elif sim.fdtd.getnamednumber('varFDTD') == 1:\n            sim.fdtd.addeffectiveindex()\n        else:\n            raise UserWarning('no FDTD or varFDTD solver object could be found.')\n        sim.fdtd.set('name', index_monitor_name)\n        sim.fdtd.setnamed(index_monitor_name, 'override global monitor settings', True)\n        sim.fdtd.setnamed(index_monitor_name, 'frequency points', 1)\n        sim.fdtd.setnamed(index_monitor_name, 'record conformal mesh when possible', True)\n        monitor_type = sim.fdtd.getnamed(monitor_name, 'monitor type')\n        geometric_props = ['monitor type']\n        geometric_props.extend(Optimization.cross_section_monitor_props(monitor_type))\n        for prop_name in geometric_props:\n            prop_val = sim.fdtd.getnamed(monitor_name, prop_name)\n            sim.fdtd.setnamed(index_monitor_name, prop_name, prop_val)\n        sim.fdtd.setnamed(index_monitor_name, 'spatial interpolation', 'none')\n\n    @staticmethod\n    def cross_section_monitor_props(monitor_type):\n        geometric_props = ['x', 'y', 'z']\n        if monitor_type == '3D':\n            geometric_props.extend(['x span','y span','z span'])\n        elif monitor_type == '2D X-normal':\n            geometric_props.extend(['y span','z span'])\n        elif monitor_type == '2D Y-normal':\n            geometric_props.extend(['x span','z span'])\n        elif monitor_type == '2D Z-normal':\n            geometric_props.extend(['x span','y span'])\n        elif monitor_type == 'Linear X':\n            geometric_props.append('x span')\n        elif monitor_type == 'Linear Y':\n            geometric_props.append('y span')\n        elif monitor_type == 'Linear Z':\n            geometric_props.append('z span')\n        else:\n            raise UserWarning('monitor should be 2D or linear for a mode expansion to be meaningful.')\n        return geometric_props\n\n    @staticmethod\n    def set_global_wavelength(sim, wavelengths):\n        sim.fdtd.setglobalmonitor('use source limits', True)\n        sim.fdtd.setglobalmonitor('use linear wavelength spacing', True)\n        sim.fdtd.setglobalmonitor('frequency points', len(wavelengths))\n        sim.fdtd.setglobalsource('set wavelength', True)\n        sim.fdtd.setglobalsource('wavelength start', wavelengths.min())\n        sim.fdtd.setglobalsource('wavelength stop', wavelengths.max())\n\n    @staticmethod\n    def set_source_wavelength(sim, source_name, multi_freq_src, freq_pts):\n        if sim.fdtd.getnamednumber(source_name) != 1:\n            raise UserWarning('a single object named ""{}"" must be defined in the base simulation.'.format(source_name))\n        if sim.fdtd.getnamed(source_name, 'override global source settings'):\n            print('Wavelength range of source object will be superseded by the global settings.')\n        sim.fdtd.setnamed(source_name, 'override global source settings', False)\n        sim.fdtd.select(source_name)\n        if sim.fdtd.haveproperty('multifrequency mode calculation'):\n            sim.fdtd.setnamed(source_name, 'multifrequency mode calculation', multi_freq_src)\n            if multi_freq_src:\n                sim.fdtd.setnamed(source_name, 'frequency points', freq_pts)\n        elif sim.fdtd.haveproperty('multifrequency beam calculation'):\n            sim.fdtd.setnamed(source_name, 'multifrequency beam calculation', multi_freq_src)\n            if multi_freq_src:\n                sim.fdtd.setnamed(source_name, 'number of frequency points', freq_pts)\n\n    @staticmethod\n    def set_use_legacy_conformal_interface_detection(sim, flagVal):\n        if sim.fdtd.getnamednumber('FDTD') == 1:\n            sim.fdtd.select('FDTD')\n        elif sim.fdtd.getnamednumber('varFDTD') == 1:\n            sim.fdtd.select('varFDTD')\n        else:\n            raise UserWarning('no FDTD or varFDTD solver object could be found.')\n        if bool(sim.fdtd.haveproperty('use legacy conformal interface detection')):\n            sim.fdtd.set('use legacy conformal interface detection', flagVal)\n            sim.fdtd.set('conformal meshing refinement', 51)\n            sim.fdtd.set('meshing tolerance', 1.0/1.134e14)\n        else:\n            raise UserWarning('install a more recent version of FDTD or the permittivity derivatives will not be accurate.')\n            \n    @staticmethod\n    def check_simulation_was_successful(sim):\n        if sim.fdtd.getnamednumber('FDTD') == 1:\n            simulation_status = sim.fdtd.getresult('FDTD','status')\n        elif sim.fdtd.getnamednumber('varFDTD') == 1:\n            simulation_status = sim.fdtd.getresult('varFDTD','status')\n        else:\n            raise UserWarning('no FDTD or varFDTD solver object could be found.')\n        if simulation_status != 1 and simulation_status != 2: # run to full simulation time (1) or autoshutoff triggered (2)\n            raise UserWarning('FDTD simulation did not complete successfully: status {0}'.format(simulation_status))\n        return simulation_status\n",new_line_char,reg_ex,reg_ex,new_line_char,new_line_char);
            file = fopen(strcat(app.folder_path,'LumOpt/optimization.py'),'w');
            fprintf(file,'%s',py);
            fclose(file);            
            
            %Build Python File (lumopt_wrapper.py)
            app.lumopt_target=string(app.TargetmEditField.Value);
            app.lumopt_perturbation=string(app.PerturbationmEditField.Value);
            app.lumopt_iterations=string(app.IterationsEditField.Value);        
            python_lib_path = app.py_path;
            python_lib_path = strrep(python_lib_path, '/', '//');   
            python_lib_path = strrep(python_lib_path, 'python', 'lib//site-packages');               
            py1 = sprintf("######## IMPORTS ########\n# General purpose imports\nimport os\nimport numpy as np\nimport scipy as sp\nimport matplotlib.pyplot as plt\nimport math\nimport lumapi\n\n# Optimization specific imports\nfrom lumopt.utilities.wavelengths import Wavelengths\nfrom lumopt.geometries.polygon import FunctionDefinedPolygon\n#from lumopt.figures_of_merit.modematch import ModeMatch\nfrom lumopt.utilities.materials import Material\nfrom lumopt.optimizers.generic_optimizers import ScipyOptimizers\nfrom lumopt.optimization import Optimization\n\nimport sys\nsys.path.insert(0, os.path.dirname(__file__))\nfrom transmission_plane import Transmission\nfrom optimization import Optimization\n\nsys.path.insert(0,'%s') \nimport cv2\n\nimport polygon_points\n\n######## DEFINE BASE SIMULATION ########\nbase_script = os.path.join(os.path.dirname(__file__), 'gray_new.fsp')\n\n######## DEFINE SPECTRAL RANGE #########\n# Global wavelength/frequency range for all the simulations\nwavelengths = Wavelengths(start = %se-6, stop = %se-6, points = 1)\n\n######## DEFINE OPTIMIZABLE GEOMETRY ########\n# The class FunctionDefinedPolygon needs a parameterized Polygon (with points ordered\n# in a counter-clockwise direction). \npoly_points = polygon_points.img2polygonpoints('%sTemp/gray.png')\n\ny_init = poly_points[:,0] #Optimize left and right sides\n\nperturbation = %se-6\ny_init = list(y_init)\nrepeat_points = []\n\nfor z in range(len(y_init) - 1):\n    a = z + 1      \n    if y_init[z] == y_init[a]:\n        repeat_points.append(y_init[z])\n        if y_init[z] < 0:\n            y_init[z] = y_init[z] - perturbation \n        elif y_init[z] >= 0:\n            y_init[z] = y_init[z] + perturbation \n    elif y_init[z] != y_init[a]:\n        if y_init[a] - perturbation <= y_init[z] <= y_init[a] + perturbation:\n            if y_init[z] < 0:\n                y_init[z] = y_init[z] - perturbation \n            elif y_init[z] >= 0:\n                y_init[z] = y_init[z] + perturbation \n    elif y_init[z] in repeat_points:\n        if y_init[z] < 0:\n            y_init[z] = y_init[z] - perturbation \n        elif y_init[z] >= 0:\n            y_init[z] = y_init[z] + perturbation \n            \ny_init = np.array(y_init)\n\ndef metasurface(params = y_init):\n    x = poly_points[:,1]\n    poly_points_new = np.column_stack((params,x))\n    return poly_points_new\n\n#%% The geometry will pass on the bounds and initial parameters to the optimizer.\nbounds = [(-1.55e-6, 1.55e-6)] * y_init.size\n# The permittivity of the material making the optimizable geometry and the permittivity of the material surrounding \n# it must be defined. Since this is a 2D simulation, the depth has no importance. The edge precision defines the\n# discretization of the edges forming the optimizable polygon. It should be set such there are at least a few points \n# per mesh cell. An effective index of 2.8 is user to simulate a 2D slab of 220 nm thickness.\noriginal_model = '%sTemp/gray.fsp'\nfdtd = lumapi.FDTD(filename = original_model, hide=True)\nfdtd.select('import')\nmaterial = fdtd.get('material')\neps_in = Material(name = material, mesh_order = 2)\ngeometry = FunctionDefinedPolygon(func = metasurface, initial_params = y_init, bounds = bounds, z = 0.05e-6, depth = 0.1e-6, eps_out = 1 ** 2, eps_in = eps_in, edge_precision = 5, dx = 1e-9) #dx = 100e-9\n\n######## DEFINE FIGURE OF MERIT ########\n# The base simulation script defines a field monitor named 'fom' at the point where we want to modematch to the 3rd mode (fundamental TE mode).\nfom = Transmission(monitor_name = 'fom', mode_number = 1, direction = 'Forward', multi_freq_src = True, target_T_fwd = lambda wl: np.ones(wl.size), norm_p = 1)\n\n######## DEFINE OPTIMIZATION ALGORITHM ########\n# This will run Scipy's implementation of the L-BFGS-B algoithm for at least 40 iterations. Since the variables are on the\n# order of 1e-6, thery are scale up to be on the order of 1.\noptimizer = ScipyOptimizers(max_iter = %s, method = 'L-BFGS-B', scaling_factor = 1e6, pgtol = 1e-10, ftol = 1e-10) # scale_initial_gradient_to = 0.25\n\n######## PUT EVERYTHING TOGETHER ########\nopt = Optimization(base_script = base_script, wavelengths = wavelengths, fom = fom, geometry = geometry, optimizer = optimizer, hide_fdtd_cad = False, use_deps = False)\n\n######## RUN THE OPTIMIZER ########\nopt.run()",python_lib_path,app.lumopt_target,app.lumopt_target,app.folder_path,app.lumopt_perturbation,app.folder_path,app.lumopt_iterations);
            file = fopen(strcat(app.folder_path,'LumOpt/lumopt_wrapper.py'),'w');
            fprintf(file,'%s',py1);
            fclose(file);            
                        
            % Run Python File (lumopt_wrapper.py)
            lumerical_python = app.api_path;
            lumerical_python = strrep(lumerical_python, 'api/matlab', 'python-3.6.8-embed-amd64/python');               
            lumerical_python = strrep(lumerical_python, 'Program Files', '"Program Files"');               
            command = [lumerical_python ' ' app.folder_path 'LumOpt/lumopt_wrapper.py'];
            system(command);            
        close(d);            
        end

        % Value changed function: MedianFilterSizeSlider
        function MedianFilterSizeSliderValueChanged(app, event)
            value = app.MedianFilterSizeSlider.Value;
            app.MedianFilterSizeSlider.Value=2*floor(value/2)+1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ML_Photonics_Designer = uifigure('Visible', 'off');
            app.ML_Photonics_Designer.Name = 'ML_Photonics_Designer';
            app.ML_Photonics_Designer.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ML_Photonics_Designer);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.DefineTargetTab = uitab(app.TabGroup);
            app.DefineTargetTab.Title = '(1) Define Target';
            app.GridLayout2 = uigridlayout(app.DefineTargetTab);
            app.ImportSpectrumButton = uibutton(app.GridLayout2, 'push');
            app.ImportSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSpectrumButtonPushed, true);
            app.ImportSpectrumButton.Tooltip = {'Import Excel file of 800 x 2 (row x column) format.'};
            app.ImportSpectrumButton.Text = 'Import Spectrum ';
            app.TargetDefinitionOptionsLabel = uilabel(app.GridLayout2);
            app.TargetDefinitionOptionsLabel.Text = 'Target Definition Options';
            app.Switch = uiswitch(app.GridLayout2, 'slider');
            app.Switch.Items = {'Lorentz. Calc.', 'Import Excel'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Import Excel';
            app.Image_Lorentzian = uiimage(app.GridLayout2);
            app.Image_Lorentzian.ImageSource = 'Lorentzian.png';
            app.ImportSpectrumfromExcelLabel = uilabel(app.GridLayout2);
            app.ImportSpectrumfromExcelLabel.Text = 'Import Spectrum from Excel';
            app.LorentzianCalculatorLabel = uilabel(app.GridLayout2);
            app.LorentzianCalculatorLabel.Text = 'Lorentzian Calculator';
            app.FWHMEditFieldLabel = uilabel(app.GridLayout2);
            app.FWHMEditFieldLabel.Text = 'FWHM (Γ):';
            app.FWHMEditField = uieditfield(app.GridLayout2, 'numeric');
            app.FWHMEditField.Limits = [0.65 1];
            app.FWHMEditField.ValueChangedFcn = createCallbackFcn(app, @FWHMEditFieldValueChanged, true);
            app.FWHMEditField.Value = 0.75;
            app.CenterX0EditFieldLabel = uilabel(app.GridLayout2);
            app.CenterX0EditFieldLabel.Text = 'Center (X0):';
            app.CenterX0EditField = uieditfield(app.GridLayout2, 'numeric');
            app.CenterX0EditField.Limits = [4 12];
            app.CenterX0EditField.ValueChangedFcn = createCallbackFcn(app, @CenterX0EditFieldValueChanged, true);
            app.CenterX0EditField.Value = 6;
            app.GenerateDesignTab = uitab(app.TabGroup);
            app.GenerateDesignTab.Title = '(2) Generate Design';
            app.GridLayout3 = uigridlayout(app.GenerateDesignTab);
            app.GenerateDesignButton = uibutton(app.GridLayout3, 'push');
            app.GenerateDesignButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDesignButtonPushed, true);
            app.GenerateDesignButton.Tooltip = {'Generate predicted image.'};
            app.GenerateDesignButton.Text = 'Generate Design';
            app.ImportGeneratorButton = uibutton(app.GridLayout3, 'push');
            app.ImportGeneratorButton.ButtonPushedFcn = createCallbackFcn(app, @ImportGeneratorButtonPushed, true);
            app.ImportGeneratorButton.Tooltip = {'Import generator file  (*.pt format).'};
            app.ImportGeneratorButton.Text = 'Import Generator';
            app.GenerationandSimulateDesignLabel = uilabel(app.GridLayout3);
            app.GenerationandSimulateDesignLabel.Text = 'Generation and Simulate Design';
            app.SetGeneratorPathLabel = uilabel(app.GridLayout3);
            app.SetGeneratorPathLabel.Text = '(1) Set Generator Path';
            app.PassTargetintoGeneratorLabel = uilabel(app.GridLayout3);
            app.PassTargetintoGeneratorLabel.Text = '(2) Pass Target into Generator';
            app.SimulateDesigninLumericalLabel = uilabel(app.GridLayout3);
            app.SimulateDesigninLumericalLabel.Text = '(3) Simulate Design in Lumerical';
            app.SimulateButton = uibutton(app.GridLayout3, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Tooltip = {'Simulate Design in Lumerical.'};
            app.SimulateButton.Text = 'Simulate';
            app.PlotElectricFieldLabel = uilabel(app.GridLayout3);
            app.PlotElectricFieldLabel.Text = '(4) Plot Electric Field';
            app.PlotButton = uibutton(app.GridLayout3, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tooltip = {'Plot Electric Field Profile'};
            app.PlotButton.Text = 'Plot';
            app.IndexEditFieldLabel = uilabel(app.GridLayout3);
            app.IndexEditFieldLabel.Text = 'Index:';
            app.IndexEditField = uieditfield(app.GridLayout3, 'numeric');
            app.IndexEditField.Limits = [1 100];
            app.IndexEditField.Value = 1;
            app.OptimizeTab = uitab(app.TabGroup);
            app.OptimizeTab.Title = '(3) Optimize';
            app.GridLayout4 = uigridlayout(app.OptimizeTab);
            app.AdjointOptimizationLabel = uilabel(app.GridLayout4);
            app.AdjointOptimizationLabel.Text = 'Adjoint Optimization';
            app.TargetmEditFieldLabel = uilabel(app.GridLayout4);
            app.TargetmEditFieldLabel.Text = 'Target (μm):';
            app.TargetmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TargetmEditField.Limits = [4 12];
            app.TargetmEditField.Value = 6;
            app.IterationsEditFieldLabel = uilabel(app.GridLayout4);
            app.IterationsEditFieldLabel.Text = 'Iterations:';
            app.IterationsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.IterationsEditField.Limits = [0 50];
            app.IterationsEditField.Value = 30;
            app.Polarizationx1y2Label = uilabel(app.GridLayout4);
            app.Polarizationx1y2Label.Text = {'Polarization '; '(x = 1, y = 2):'};
            app.Polarizationx1y2EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Polarizationx1y2EditField.Limits = [1 2];
            app.Polarizationx1y2EditField.Enable = 'off';
            app.Polarizationx1y2EditField.Value = 1;
            app.RunButton = uibutton(app.GridLayout4, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PerturbationmEditFieldLabel = uilabel(app.GridLayout4);
            app.PerturbationmEditFieldLabel.Text = 'Perturbation (μm):';
            app.PerturbationmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PerturbationmEditField.Limits = [0 0.2];
            app.MedianFilterSizeSliderLabel = uilabel(app.GridLayout4);
            app.MedianFilterSizeSliderLabel.Text = 'Median Filter Size:';
            app.MedianFilterSizeSlider = uislider(app.GridLayout4);
            app.MedianFilterSizeSlider.Limits = [3 9];
            app.MedianFilterSizeSlider.MajorTicks = [3 5 7 9];
            app.MedianFilterSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MedianFilterSizeSliderValueChanged, true);
            app.MedianFilterSizeSlider.MinorTicks = [];
            app.MedianFilterSizeSlider.Value = 5;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout1 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout1);
            xlabel(app.UIAxes, 'Wavelength (µm)')
            ylabel(app.UIAxes, 'Absorption')
            app.UIAxes.XLim = [4 12];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [4 6 8 10 12];
            app.UIAxes.XTickLabel = {'4'; '6'; '8'; '10'; '12'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes3 = uiaxes(app.GridLayout1);
            app.UIAxes3.Visible = 'off';
            app.Image2 = uiimage(app.GridLayout1);
            app.Image2.ImageSource = 'EM_Simulation.PNG';
            app.EMFieldProfileLabel = uilabel(app.GridLayout1);
            app.EMFieldProfileLabel.Text = 'EM Field Profile';
            app.UITable = uitable(app.GridLayout1);
            app.UITable.ColumnName = {'Wavelength (um)'; 'Absorption'};
            app.UITable.RowName = {};
            app.MLGeneratedStructureLabel = uilabel(app.GridLayout1);
            app.MLGeneratedStructureLabel.Text = 'ML-Generated Structure';
            app.Image = uiimage(app.GridLayout1);
            app.Image.ImageSource = 'ML-Generated Structure.PNG';
            app.TargetSpectrumPointsLabel = uilabel(app.GridLayout1);
            app.TargetSpectrumPointsLabel.Tooltip = {'NOTE: Updating the table will only update the Target Spectrum Plot.'};
            app.TargetSpectrumPointsLabel.Text = 'Target Spectrum Points*';
            app.TargetSpectrumPlotLabel = uilabel(app.GridLayout1);
            app.TargetSpectrumPlotLabel.Text = 'Target Spectrum Plot';
            app.ImageGAN = uiimage(app.GridLayout1);
            app.ImageGAN.Visible = 'off';
            app.MaterialLabel = uilabel(app.GridLayout1);
            app.MaterialLabel.Visible = 'off';
            app.MaterialLabel.Text = 'Material Property (ω or n)';
            app.ThicknessnmLabel = uilabel(app.GridLayout1);
            app.ThicknessnmLabel.Visible = 'off';
            app.ThicknessnmLabel.Text = 'Thickness (nm)';
            app.MaterialLabelValue = uieditfield(app.GridLayout1, 'numeric');
            app.MaterialLabelValue.Editable = 'off';
            app.MaterialLabelValue.Visible = 'off';
            app.ThicknessLabelValue = uieditfield(app.GridLayout1, 'numeric');
            app.ThicknessLabelValue.Editable = 'off';
            app.ThicknessLabelValue.Visible = 'off';
            app.PreferencesMenu = uimenu(app.ML_Photonics_Designer);
            app.PreferencesMenu.Text = 'Preferences';
            app.SetPythonPathMenu = uimenu(app.PreferencesMenu);
            app.SetPythonPathMenu.MenuSelectedFcn = createCallbackFcn(app, @SetPythonPathMenuSelected, true);
            app.SetPythonPathMenu.Text = 'Set Python Path';
            app.SetLumericalWorkingDirectoryMenu = uimenu(app.PreferencesMenu);
            app.SetLumericalWorkingDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @SetLumericalWorkingDirectoryMenuSelected, true);
            app.SetLumericalWorkingDirectoryMenu.Text = 'Set Lumerical Working Directory';
            app.SetAPIPathMenu = uimenu(app.PreferencesMenu);
            app.SetAPIPathMenu.MenuSelectedFcn = createCallbackFcn(app, @SetAPIPathMenuSelected, true);
            app.SetAPIPathMenu.Text = 'Set API Path';
            app.HelpDocumentationMenu = uimenu(app.PreferencesMenu);
            app.HelpDocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpDocumentationMenuSelected, true);
            app.HelpDocumentationMenu.Text = 'Help Documentation';
            % Show the figure after all components are created
            app.ML_Photonics_Designer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ML_Opt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ML_Photonics_Designer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ML_Photonics_Designer)
        end
    end
end