classdef SetPythonPath < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        OkButton             matlab.ui.control.Button
        PythonPathEditField  matlab.ui.control.EditField
        PythonPathLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            GuiWidth = 300;
            GuiHeight = 200;
            left = screenWidth*0.5;
            bottom = screenHeight*0.5;
            app.UIFigure.Position = [left-GuiWidth/2 bottom-GuiHeight/2 GuiWidth GuiHeight]; 
            drawnow;
            app.CallingApp = mainapp;
            
            if ispref('ganapppref','py_path')
               app.PythonPathEditField.Value = getpref('ganapppref', 'py_path');
            end
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            updatePy(app.CallingApp, app.PythonPathEditField.Value);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PythonPathLabel = uilabel(app.GridLayout);
            app.PythonPathLabel.Text = 'Python Path';
            app.PythonPathEditField = uieditfield(app.GridLayout, 'text');
            app.PythonPathEditField.Tooltip = {'Path to python.exe with Pytorch installed.'};
            app.OkButton = uibutton(app.GridLayout, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetPythonPath(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end